classdef Import < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataViewerUIFigure       matlab.ui.Figure
        Menu                     matlab.ui.container.Menu
        OpenFolderMenu           matlab.ui.container.Menu
        OpenFileMenu             matlab.ui.container.Menu
        QuitMenu                 matlab.ui.container.Menu
        GraphMenu                matlab.ui.container.Menu
        SQIMenu                  matlab.ui.container.Menu
        ESUMenu                  matlab.ui.container.Menu
        dFRCMenu                 matlab.ui.container.Menu
        dRRMenu                  matlab.ui.container.Menu
        dTVMenu                  matlab.ui.container.Menu
        showremovedMenu          matlab.ui.container.Menu
        FilterMenu               matlab.ui.container.Menu
        Median7                  matlab.ui.container.Menu
        Median5                  matlab.ui.container.Menu
        Median3                  matlab.ui.container.Menu
        None                     matlab.ui.container.Menu
        LineStyleMenu            matlab.ui.container.Menu
        dotMenu                  matlab.ui.container.Menu
        lineMenu                 matlab.ui.container.Menu
        circleoMenu              matlab.ui.container.Menu
        MarkerSizeMenu           matlab.ui.container.Menu
        Size3                    matlab.ui.container.Menu
        Size2                    matlab.ui.container.Menu
        Size1                    matlab.ui.container.Menu
        DataselectionMenu        matlab.ui.container.Menu
        CaptureMenu              matlab.ui.container.Menu
        ImageMenu                matlab.ui.container.Menu
        ScaleMenu                matlab.ui.container.Menu
        Scale_Auto               matlab.ui.container.Menu
        Scale_1500mL             matlab.ui.container.Menu
        Scale_1000mL             matlab.ui.container.Menu
        Scale_500mL              matlab.ui.container.Menu
        Scale_200mL              matlab.ui.container.Menu
        Scale_100mL              matlab.ui.container.Menu
        ColormapMenu             matlab.ui.container.Menu
        BPMenu                   matlab.ui.container.Menu
        BRMenu                   matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        DataViewTab              matlab.ui.container.Tab
        PatientInfoPanel         matlab.ui.container.Panel
        NameEditFieldLabel       matlab.ui.control.Label
        NameEditField            matlab.ui.control.EditField
        AgeEditFieldLabel        matlab.ui.control.Label
        AgeEditField             matlab.ui.control.NumericEditField
        WeightEditFieldLabel     matlab.ui.control.Label
        WeightEditField          matlab.ui.control.NumericEditField
        HeightEditFieldLabel     matlab.ui.control.Label
        HeightEditField          matlab.ui.control.NumericEditField
        GenderEditFieldLabel     matlab.ui.control.Label
        GenderEditField          matlab.ui.control.EditField
        DataInfoPanel            matlab.ui.container.Panel
        DurationEditFieldLabel   matlab.ui.control.Label
        DurationEditField        matlab.ui.control.EditField
        TypeDropDownLabel        matlab.ui.control.Label
        TypeDropDown             matlab.ui.control.DropDown
        VersionButton            matlab.ui.control.Button
        DateEditFieldLabel       matlab.ui.control.Label
        DateEditField            matlab.ui.control.EditField
        OptionButton             matlab.ui.control.Button
        Enable_Data              matlab.ui.control.Lamp
        Tree                     matlab.ui.container.Tree
        ImportButton             matlab.ui.control.Button
        Graph_AT                 matlab.ui.container.Panel
        TextLoading              matlab.ui.control.EditField
        NumericViewTab           matlab.ui.container.Tab
        UITable                  matlab.ui.control.Table
        SampleRateDropDownLabel  matlab.ui.control.Label
        SampleRateDropDown       matlab.ui.control.DropDown
        TableExportButton        matlab.ui.control.Button
        ImageViewTab             matlab.ui.container.Tab
        Image                    matlab.ui.control.Image
        PEEPdeterminationTab     matlab.ui.container.Tab
        DatePickerLabel          matlab.ui.control.Label
        DatePicker               matlab.ui.control.DatePicker
    end

    
    properties (Access = private)
        Data; % Description
        Tiledlayout_1 % Description
        TV % Description
        Wave % Description        
        Table % Description
        Opt % Description
    end
    
    methods (Access = private)
        
        function h = PlotGraph(app)
            line_width = app.Opt.Graph.line_width; 
            avg_num = app.Opt.Graph.avg_num;  
            label_size = app.Opt.Graph.label_size;
            marker_type = app.Opt.Graph.marker_type;
            marker_size = app.Opt.Graph.marker_size;
            if strcmp(marker_type,'o')
                marker_size = marker_size/2;
            end
            removedmarker_size = app.Opt.Graph.marker_size/3;
            
            %% SQI
            sqi_total = true(length(app.TV),1);
            if app.dFRCMenu.Checked
                sqi_total = sqi_total & [app.TV.sqi_dFRC]';
            end
            if app.dRRMenu.Checked
                sqi_total = sqi_total & [app.TV.sqi_dRR]';
            end
            if app.dTVMenu.Checked
                sqi_total = sqi_total & [app.TV.sqi_dTV]';
            end
            if app.ESUMenu.Checked
                sqi_total = sqi_total & [app.TV.sqi_ESU]';
            end

%             figure;
%             yyaxis('right'); 
%             plot([app.TV(sqi_total).t_insp],movmedian([app.TV(sqi_total).TVi],[1 1]*avg_num),marker_type,'color',[0 0 0],'linewidth',line_width,'MarkerSize',marker_size); hold on;
%             plot([app.TV(~sqi_total).t_insp],[app.TV(~sqi_total).TVi],marker_type,'color',[0.8 0.8 0.8],'linewidth',line_width,'MarkerSize',marker_size);
%             yyaxis('left');
%             sqi_total = false(size(sqi_total));
            
            %% Display
            cnt_fig = 1;
            h(cnt_fig) = nexttile(app.Tiledlayout_1,cnt_fig);
            yyaxis(h(cnt_fig),'right'); plot(h(cnt_fig),app.Wave.t_hms,app.Wave.RVS,'linewidth',line_width,'color',[0.00,0.45,0.74]); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
            yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),'RVS','FontWeight','bold','Color','k','fontsize',label_size); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
            
            cnt_fig = cnt_fig + 1;
            h(cnt_fig) = nexttile(app.Tiledlayout_1,cnt_fig);
            yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),[app.TV(sqi_total).t_insp],movmedian([app.TV(sqi_total).TVi],[1 1]*avg_num),marker_type,'color',[0 0 0],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
            if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),[app.TV(~sqi_total).t_insp],[app.TV(~sqi_total).TVi],'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
            yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),'TV','FontWeight','bold','Color','k','fontsize',label_size); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
            
            cnt_fig = cnt_fig + 1;
            h(cnt_fig) = nexttile(app.Tiledlayout_1,cnt_fig);
            yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),[app.TV(sqi_total).t_insp],movmedian([app.TV(sqi_total).dP],[1 1]*avg_num),marker_type,'color',[0.3010 0.7450 0.9330],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
            if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),[app.TV(~sqi_total).t_insp],[app.TV(~sqi_total).dP],'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
            yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),'{\Delta}P','FontWeight','bold','Color','k','fontsize',label_size); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
            
            cnt_fig = cnt_fig + 1;
            h(cnt_fig) = nexttile(app.Tiledlayout_1,cnt_fig);
            yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),[app.TV(sqi_total).t_insp],movmedian([app.TV(sqi_total).Cdyn]*1000,[1 1]*avg_num),marker_type,'color',[1 0 1],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
            if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),[app.TV(~sqi_total).t_insp],[app.TV(~sqi_total).Cdyn],'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
            yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),'Cdyn','FontWeight','bold','Color','k','fontsize',label_size); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
            
            cnt_fig = cnt_fig + 1;
            h(cnt_fig) = nexttile(app.Tiledlayout_1,cnt_fig);
            yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),[app.TV(sqi_total).t_insp],movmedian([app.TV(sqi_total).PEEP],[1 1]*avg_num),marker_type,'color',[0 1 1],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
            if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),[app.TV(~sqi_total).t_insp],[app.TV(~sqi_total).PEEP],'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
            yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),'PEEP','FontWeight','bold','Color','k','fontsize',label_size); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
            
            cnt_fig = cnt_fig + 1;
            h(cnt_fig) = nexttile(app.Tiledlayout_1,cnt_fig);
            yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),[app.TV(sqi_total).t_insp],movmedian([app.TV(sqi_total).GI],[1 1]*avg_num),marker_type,'color',[0.9290 0.6940 0.1250],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % ylim([0.75 1.1]);
            if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),[app.TV(~sqi_total).t_insp],[app.TV(~sqi_total).GI],'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
            yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),'GI','FontWeight','bold','Color','k','fontsize',label_size); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
            
            cnt_fig = cnt_fig + 1;
            h(cnt_fig) = nexttile(app.Tiledlayout_1,cnt_fig);
            yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),[app.TV(sqi_total).t_insp],movmedian([app.TV(sqi_total).sdRVD],[1 1]*avg_num),marker_type,'color',[0.4940 0.1840 0.5560],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0,'margin',1.2);
            if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),[app.TV(~sqi_total).t_insp],[app.TV(~sqi_total).sdRVD],'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
            yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),'SD(RVD)','FontWeight','bold','Color','k','fontsize',label_size); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
            
            cnt_fig = cnt_fig + 1;
            h(cnt_fig) = nexttile(app.Tiledlayout_1,cnt_fig);
            yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),[app.TV(sqi_total).t_insp],movmedian([app.TV(sqi_total).sdPopen],[1 1]*avg_num),marker_type,'color',[0.3010 0.7450 0.9330],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0,'margin',1.2);
            if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),[app.TV(~sqi_total).t_insp],[app.TV(~sqi_total).sdPopen],'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
            yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),'SD(Popen)','FontWeight','bold','Color','k','fontsize',label_size); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
            
            cnt_fig = cnt_fig + 1;
            h(cnt_fig) = nexttile(app.Tiledlayout_1,cnt_fig);
            yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig),"off"); plot(h(cnt_fig),[app.TV(sqi_total).t_insp],movmedian([app.TV(sqi_total).CoVx]*100,[1 1]*avg_num),marker_type,'color',[0.6350 0.0780 0.1840],'linewidth',line_width,'MarkerSize',marker_size); hold(h(cnt_fig),"on"); 
            yyaxis(h(cnt_fig),'right'); plot(h(cnt_fig),[app.TV(sqi_total).t_insp],movmedian([app.TV(sqi_total).CoVy]*100,[1 1]*avg_num),marker_type,'color',[0.4660 0.6740 0.1880],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'YColor','k'); yline(h(cnt_fig),50,'k');
            if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),[app.TV(~sqi_total).t_insp],[app.TV(~sqi_total).CoVx]*100,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
            if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),[app.TV(~sqi_total).t_insp],[app.TV(~sqi_total).CoVy]*100,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
            yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),'CoVxy','FontWeight','bold','Color','k','fontsize',label_size); set(h(cnt_fig),'YTickLabel',[]); 
            linkaxes(h,'x');
        end
        
        function ImportButtonEnable(app,isenable)
            if strcmp(isenable, 'on')
                app.OptionButton.Enable = 'on';
                app.VersionButton.Enable = 'on';
                app.ImportButton.Enable = 'on';
                app.Tree.Enable = 'on';
                app.AgeEditField.Enable = 'on';
                app.WeightEditField.Enable = 'on';
                app.HeightEditField.Enable = 'on';
                app.DurationEditField.Enable = 'on';
                app.DateEditField.Enable = 'on';
            else
                app.OptionButton.Enable = 'off';
                app.VersionButton.Enable = 'off';
                app.ImportButton.Enable = 'off';
                app.Tree.Enable = 'off';
                app.AgeEditField.Enable = 'off';
                app.WeightEditField.Enable = 'off';
                app.HeightEditField.Enable = 'off';
                app.DurationEditField.Enable = 'off';
                app.DateEditField.Enable = 'off';
            end
            drawnow;
        end
        
        function TableUpdate(app)
            Table_fs = app.SampleRateDropDown.Value;
            
            TV_Table_name = ["Time", "TVi", "TVe", "RR", "I:E", "MV", "PIP", "PEEP", "dP", ...
                "Cdyn", "dFRC", "sdRVD", "sdPopen", "GI", "CoVx", "CoVy"];
            
            %% SQI
            sqi_total = true(length(app.TV),1);
            if app.dFRCMenu.Checked
                sqi_total = sqi_total & [app.TV.sqi_dFRC]';
            end
            if app.dRRMenu.Checked
                sqi_total = sqi_total & [app.TV.sqi_dRR]';
            end
            if app.dTVMenu.Checked
                sqi_total = sqi_total & [app.TV.sqi_dTV]';
            end
            if app.ESUMenu.Checked
                sqi_total = sqi_total & [app.TV.sqi_ESU]';
            end
           
            temp_table = timetable([app.TV(sqi_total).t_insp]', ...
                movmedian([app.TV(sqi_total).TVi],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).TVe],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).RR],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).IE],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).MV],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).PIP],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).PEEP],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).dP],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).Cdyn],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).dFRC],[1 1]*app.Opt.Graph.avg_num)',  ...
                movmedian([app.TV(sqi_total).sdRVD],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).sdPopen],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).GI],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).CoVx],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.TV(sqi_total).CoVy],[1 1]*app.Opt.Graph.avg_num)');
%             movmedian([app.TV.PEEP],[1 1]*avg_num)

            switch Table_fs
                case '1 min (average)'
                    time_start = dateshift(temp_table.Time(1), 'start', 'minute');
                    time_end = dateshift(temp_table.Time(end), 'end', 'minute');
                    time_resample = time_start:minutes(1):time_end;
                    temp_table = retime(temp_table,time_resample,'mean');
                    temp_table(isnan(table2array(temp_table(:,1))),:) = [];
                case '10 min (average)'
                    time_start = dateshift(temp_table.Time(1), 'start', 'minute');
                    time_end = dateshift(temp_table.Time(end), 'end', 'minute');
                    time_resample = time_start:minutes(10):time_end;
                    temp_table = retime(temp_table,time_resample,'mean');
                    temp_table(isnan(table2array(temp_table(:,1))),:) = [];
                case '30 min (average)'
                    time_start = dateshift(temp_table.Time(1), 'start', 'minute');
                    time_end = dateshift(temp_table.Time(end), 'end', 'minute');
                    time_resample = time_start:minutes(30):time_end;
                    temp_table = retime(temp_table,time_resample,'mean');
                    temp_table(isnan(table2array(temp_table(:,1))),:) = [];
                case '1 hour (average)'
                    time_start = dateshift(temp_table.Time(1), 'start', 'minute');
                    time_end = dateshift(temp_table.Time(end), 'end', 'minute');
                    time_resample = time_start:hours(1):time_end;
                    temp_table = retime(temp_table,time_resample,'mean');
                    temp_table(isnan(table2array(temp_table(:,1))),:) = []; 
                case 'Selection'
%                     time_resample = time vector
%                     temp_table = retime(temp_table,time_resample,'mean');
%                     temp_table(isnan(table2array(temp_table(:,1))),:) = []; 
            end

            app.UITable.Data = timetable2table(temp_table);
            app.UITable.ColumnName = TV_Table_name;
            app.Table = temp_table;
            app.Table.Properties.VariableNames = TV_Table_name(2:end);
            % sample rate 변경부분
        end
        
        function results = SQI(app)
%             if isempty(app.TV)
%                 sqi.dFRC = abs([Result.TVi_au]-[Result.TVe_au])./(([Result.TVi_au]+[Result.TVe_au])/2);
%                 sqi.dFRC = ~movmax((sqi.dFRC > 0.15),[1 1]); % good
%                 sqi.RR = opt.sqi_dRR > movstd([Result.RR],[1 1])./movmean([Result.RR],[1 1]);
%                 sqi.TV = opt.sqi_TV(2) > [Result.TVi_au2ml_same] & [Result.TVi_au2ml_same] > opt.sqi_TV(1);
%                 sqi.good = movmin(sqi.dFRC & sqi.TV & sqi.RR, [1 1]);
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Data.session = [];
            app.Data.patient = [];
            app.Data.error_info = [];
            app.OptionButton.Enable = 'off';
            app.VersionButton.Enable = 'off';
            app.ImportButton.Enable = 'off';
%             app.FilterMenu.Children(1).Checked = 'on';
%             app.FilterMenu.Children(1).HandleVisibility = 'off';

            % graph
            app.Opt.Graph.avg_num = 3;
            app.Opt.Graph.label_size = 10;
            app.Opt.Graph.line_width = 2;
            app.Opt.Graph.marker_size = 7;
            app.Opt.Graph.marker_type = '.';
            
            % SQI
%             app.Opt.sqi_dRR = 0.15;
%             app.Opt.sqi_TV = [10 2000];
            app.Opt.show_removed = app.showremovedMenu.Checked;
            
            app.Tiledlayout_1 = tiledlayout(app.Graph_AT,9,1,'Padding','compact','TileSpacing','compact');
            drawnow;
            
            %% window resize
            monit_size = get(0, 'MonitorPositions') ;
            monit_size = monit_size(1,:);
            window_size = app.DataViewerUIFigure.Position;
            if monit_size(4) < sum(window_size([2 4])) % check over height
                resize_ratio = (monit_size(4)-window_size(2)*2.5)/window_size(4);
                app.DataViewerUIFigure.Position(3:4) = resize_ratio * app.DataViewerUIFigure.Position(3:4);
            end
            window_size = app.DataViewerUIFigure.Position;
            if monit_size(3) < sum(window_size([1 3])) % check over width
                resize_ratio = (monit_size(3)-window_size(1)*2.5)/window_size(3);
                app.DataViewerUIFigure.Position(3:4) = resize_ratio * app.DataViewerUIFigure.Position(3:4);
            end
            app.TextLoading.Visible = 0;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.OptionButton.Enable = 'on';
            app.VersionButton.Enable = 'on';
%             app.Tree.SelectedNodes(1) = [];
            
            %% 상위노드 선택시 하위노드 전체 선택
            for cnt = 1:length(app.Tree.SelectedNodes)
                app.Tree.SelectedNodes = [app.Tree.SelectedNodes; app.Tree.Children(strcmp({app.Data.patient.ID}, app.Tree.SelectedNodes(cnt).Text)).Children];
            end
%             for cnt = 1:length(app.Tree.SelectedNodes)
%                 if length(app.Tree.SelectedNodes(cnt).NodeData) > 1
%                     app.Tree.SelectedNodes = [app.Tree.SelectedNodes; app.Tree.Children(strcmp({app.Data.patient.ID}, app.Tree.SelectedNodes(cnt).Text)).Children];
% %                     strcmp({app.Data.patient.ID}, selectedNodes(cnt).Text)
%                 end
%             end

            %% Data missing된 session 제외
            for cnt = length(app.Tree.SelectedNodes):-1:1
                if length(app.Tree.SelectedNodes(cnt).NodeData) > 1 % case multi node
                    cnt_node = 1; cnt_stop = false;
                    while((cnt_node<length(app.Tree.SelectedNodes(cnt).NodeData)) && ~cnt_stop)
                        if app.Data.session(app.Tree.SelectedNodes(cnt).NodeData(cnt_node)).enable_data == 0
                            if length(app.Tree.SelectedNodes) > 1
                                app.Tree.SelectedNodes(cnt) = [];
                                cnt_stop = true;
                            end
                        end
                        cnt_node = cnt_node + 1;
                    end
                else
                    if app.Data.session(app.Tree.SelectedNodes(cnt).NodeData).enable_data == 0
                        if length(app.Tree.SelectedNodes) > 1
                            app.Tree.SelectedNodes(cnt) = [];
                        end
                    end
                end
            end      
%             selectedNodes = app.Tree.SelectedNodes;
                        
            %% Node 판별
            t_Node = false(length(app.Data.session),1);
            for cnt = 1:length(app.Tree.SelectedNodes)
                t_Node([app.Tree.SelectedNodes.NodeData]) = true;
            end
            
            if length(app.Tree.SelectedNodes) > 1
                idx_Node = unique([app.Tree.SelectedNodes.NodeData]);
                for cnt_node = length(idx_Node):-1:1
                    if app.Data.session(cnt_node).enable_data == 0
                        idx_Node(cnt_node) = [];
                    end
                end
                txt_Node = app.Tree.SelectedNodes(1).Text;
                t_duration = sum([app.Data.session(idx_Node).duration]);
            else
                idx_Node = app.Tree.SelectedNodes.NodeData;
                txt_Node = app.Tree.SelectedNodes.Text;
                
                if length(idx_Node) > 1
                    t_duration = sum([app.Data.session(idx_Node).duration]);
                else
                    t_duration = app.Data.session(idx_Node).duration;
                end
            end
            
            if length(app.Tree.SelectedNodes(end).NodeData) > 1
                app.Enable_Data.Color = [1 1 1];
            else
                if app.Data.session(app.Tree.SelectedNodes(end).NodeData).enable_data == 0
                    app.Enable_Data.Color = [1 0 0];
                else
                    app.Enable_Data.Color = [0 1 0];
                end
            end
            
%             app.NameEditField.Enable = 'on';
%             app.AgeEditField.Enable = 'on';
%             app.HeightEditField.Enable = 'on';
%             app.WeightEditField.Enable = 'on';
%             app.GenderEditField.Enable = 'on';
%             
%             app.DurationEditField.Enable = 'on';
%             app.DateEditField.Enable = 'on';
            app.TypeDropDown.Enable = 'off';
            
            app.NameEditField.Value = [app.Data.session(idx_Node(1)).info_p.Name_first ' ' app.Data.session(idx_Node(1)).info_p.Name_last];
            app.AgeEditField.Value = app.Data.session(idx_Node(1)).info_p.Age;
            app.HeightEditField.Value = app.Data.session(idx_Node(1)).info_p.H;
            app.WeightEditField.Value = app.Data.session(idx_Node(1)).info_p.W;
            app.GenderEditField.Value = app.Data.session(idx_Node(1)).info_p.Gender;
            
            app.DurationEditField.Value = [num2str(round(t_duration,2)) ' hour'];
            app.DateEditField.Value = app.Data.session(idx_Node(1)).date;
            app.TypeDropDown.Value = 'AirTom';
            
            app.Data.idx_Node = idx_Node;
            app.Data.txt_Node = txt_Node;
            app.Data.duration_Node = t_duration;

        end

        % Button pushed function: OptionButton
        function OptionButtonPushed(app, event)
%             app.Import_option = 
            Import_option(app,app.Data);
            app.OptionButton.Enable = 'off';
        end

        % Close request function: DataViewerUIFigure
        function DataViewerUIFigureCloseRequest(app, event)
            try
                if isfield(app, 'Import_Option')
                    delete(app.Import_Option);
                end
                if isfield(app, 'Import_Version')
                    delete(app.Import_Version);
                end
                delete(app);
            catch
                delete(app);
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            %% Button disable
            ImportButtonEnable(app,'off');
%             app.OptionButton.Enable = 'off';
%             app.VersionButton.Enable = 'off';
%             app.DatapathButton.Enable = 'off';
%             app.ImportButton.Enable = 'off';
%             app.Tree.Enable = 'off';
%             app.AgeEditField.Enable = 'off';
%             app.WeightEditField.Enable = 'off';
%             app.HeightEditField.Enable = 'off';
%             app.DurationEditField.Enable = 'off';
%             app.DateEditField.Enable = 'off';
            
            %% Create result path
            if ~isfolder([app.Data.patient(1).path_root '\result'])
                mkdir([app.Data.patient(1).path_root '\result']);
            end
            
            %% Node info -> patient cat
            if isfield(app.Data,'idx_Node')
                idx_Node = sort(app.Data.idx_Node);
            else
                ImportButtonEnable(app,'on')
                errordlg('Patient is not selected.','error');
            end
            
            cnt_patient = 1;
            for cnt = 1:length(app.Data.patient)
                idx_member = intersect(app.Data.patient(cnt).idx_session, idx_Node);
                if ~isempty(idx_member)
                    path_patient(cnt_patient).name = app.Data.patient(cnt).ID;
                    path_patient(cnt_patient).path_data(1:length(idx_member)) = {app.Data.session(idx_member).path};
                    cnt_patient = cnt_patient + 1;
                end
            end
            clear cnt_patient;
            
            app.TextLoading.Visible = 'on';
            app.Graph_AT.Visible = 'off';
            %% Data import
            for cnt = 1:length(path_patient)
                for cnt_s = 1:length(path_patient(cnt).path_data)
                    %% session 내의 EIT raw file 갯수 파악
                    dirlist = dir([path_patient(cnt).path_data{cnt_s} '\' 'RawData']);
                    n_eit = 0;
                    for cnt_file = 1:length(dirlist)
                        temp_string = strsplit(dirlist(cnt_file).name,{'.', '['});
                        if contains(temp_string{1},'EIT_','IgnoreCase',true)
                            n_eit = n_eit + 1;
                        end
                        
                        if contains(temp_string{1},'EITImpedance_','IgnoreCase',true)
                            n_eit = n_eit + 1;
                        end
                        
                        if contains(temp_string{1},'EITGain_','IgnoreCase',true)
                            n_eit = n_eit + 1;
                        end
                    end
                    
                    %% file by file
                    n_overlap = 10*100; % 10s data overlap
                    for cnt_file = 1:n_eit
                        if cnt_file == 1 || isempty(app.TV) % first data
                            % ******** EIT import (block by block) ********
                            EIT_raw = FxImport_EIT_app(path_patient(cnt).path_data{cnt_s},'block',cnt_file,'disp',0);
                            % HV (?)
                            clear temp;
                            temp.EIT.t_hms = EIT_raw.EIT.t_hms;
                            temp.EIT.ohm = EIT_raw.EIT.ohm;
                            temp.EIT.Settings = EIT_raw.EIT.Settings;
                            temp.Pressure.Faw = EIT_raw.Pressure.Faw;
                            temp.Pressure.Paw = EIT_raw.Pressure.Paw;
                            temp.idx_session = cnt_s;
                            temp.idx_file = cnt_file;
                            temp.path = EIT_raw.path;
                            
                            if ~isfield(temp.EIT.Settings, 'Elec_config') 
                                temp.EIT.Settings.Elec_config = 0;
                            end
                            
                            if cnt_s == 1
                                [app.TV, temp] = FxParam_AT_v1_app(temp); % first session for one patient
                                app.Wave.t_hms = temp.EIT.t_hms;
                                app.Wave.RVS = temp.EIT.RVS;
                                app.Wave.Faw = temp.Pressure.Faw;
                                app.Wave.Paw = temp.Pressure.Paw;
                            else
                                [temp_TV, temp] = FxParam_AT_v1_app(temp);
                                app.TV = [app.TV temp_TV]; % stack! in same patient
                                app.Wave.t_hms = [app.Wave.t_hms temp.EIT.t_hms];
                                app.Wave.RVS = [app.Wave.RVS temp.EIT.RVS];                                
                                app.Wave.Faw = [app.Wave.Faw temp.Pressure.Faw];
                                app.Wave.Paw = [app.Wave.Paw temp.Pressure.Paw];
                            end
                            
                        else % others
                            % ******** EIT import (block by block) ********
                            EIT_raw = FxImport_EIT_app(path_patient(cnt).path_data{cnt_s},'block',cnt_file,'disp',0);
                            temp.EIT = rmfield(temp.EIT, 'ohm_HPF');
                            temp.EIT = rmfield(temp.EIT, 'RVS');
                            temp.EIT = rmfield(temp.EIT, 'Sat');
                            
                            temp.EIT.t_hms(1:end-n_overlap) = [];
                            temp.EIT.ohm(:,1:end-n_overlap) = [];
                            temp.Pressure.Faw(1:end-n_overlap) = [];
                            temp.Pressure.Paw(1:end-n_overlap) = [];
                            
                            temp.EIT.t_hms = [temp.EIT.t_hms EIT_raw.EIT.t_hms];
                            temp.EIT.ohm = [temp.EIT.ohm EIT_raw.EIT.ohm];
                            temp.Pressure.Faw = [temp.Pressure.Faw EIT_raw.Pressure.Faw];
                            temp.Pressure.Paw = [temp.Pressure.Paw EIT_raw.Pressure.Paw];
                            temp.idx_file = cnt_file;
                            
                            [temp_TV, temp] = FxParam_AT_v1_app(temp);
                            
                            if ~isempty(temp_TV)
                                if sum(app.TV(end).t_insp > [temp_TV.t_insp]) > 0 % remove overlap data
                                    temp_TV(1:sum(app.TV(end).t_insp > [temp_TV.t_insp])) = [];
                                end
                                app.TV = [app.TV temp_TV]; % stack! in same patient
                            end
                            app.Wave.t_hms = [app.Wave.t_hms temp.EIT.t_hms(n_overlap+1:end)];
                            app.Wave.RVS = [app.Wave.RVS temp.EIT.RVS(n_overlap+1:end)];
                            app.Wave.Faw = [app.Wave.Faw temp.Pressure.Faw(n_overlap+1:end)];
                            app.Wave.Paw = [app.Wave.Paw temp.Pressure.Paw(n_overlap+1:end)];
                            
                        end
                        disp(['patient: ' num2str(cnt) '/' num2str(length(path_patient)) ...
                           ' , session: ' num2str(cnt_s) '/' num2str(length(path_patient(cnt).path_data)) ...
                           ' , file: ' num2str(cnt_file) '/' num2str(n_eit)]);
                        app.TextLoading.Value = ['patient: ' num2str(cnt) '/' num2str(length(path_patient)) ...
                           ' , session: ' num2str(cnt_s) '/' num2str(length(path_patient(cnt).path_data)) ...
                           ' , file: ' num2str(cnt_file) '/' num2str(n_eit)];
                       drawnow;
                    end % end for one session
                end % end for one patient
%                 app.TV = TV;
%                 app.Wave = Wave;
%                 app.Table = TV_Table
                  
                if ~isempty(app.TV)
                    temp_file_name = [app.Data.patient(cnt).path_root '\result\' app.Data.patient(cnt).ID '_0'];
                    while isfile([temp_file_name '.mat'])
                        temp_file_name = [temp_file_name(1:end-1) num2str(str2double(temp_file_name(end))+1)];
                    end
                    
                    % need to optimaze for memory
                    TV = app.TV; Wave = app.Wave;
                    save(temp_file_name, 'TV' ,'Wave' ,'-v7.3','-nocompression');
                    clear TV Wave;
                    
                    TV_Table_name = ["Time", "TVi", "TVe", "RR", "I:E", "MV", "PIP", "PEEP", "dP", ...
                                     "Cdyn", "dFRC", "sdRVD", "sdPopen", "GI", "CoVx", "CoVy"];
                    app.Table = timetable([app.TV.t_insp]', [app.TV.TVi]', [app.TV.TVe]', [app.TV.RR]', [app.TV.IE]', ...
                                     [app.TV.MV]', [app.TV.PIP]', [app.TV.PEEP]', [app.TV.dP]', [app.TV.Cdyn]', [app.TV.dFRC]',  ...
                                     [app.TV.sdRVD]', [app.TV.sdPopen]', [app.TV.GI]', [app.TV.CoVx]', [app.TV.CoVy]');
                    app.Table.Properties.VariableNames = TV_Table_name(2:end);
                    app.UITable.Data = timetable2table(app.Table);
                    app.UITable.ColumnName = TV_Table_name;
                    
                    app.UITable.Enable = "on";
                    app.UITable.Visible = "on";
%                     app.TabGroup.SelectedTab = app.GraphViewTab;
                    
                    %% graph
                    PlotGraph(app);

                    %% export graph (.png)
                    exportgraphics(app.Graph_AT,[temp_file_name '.png']);
                else
                    errordlg(['Import error ID: ' app.Data.patient(cnt).ID],'Data import fail');
                end
                clear TV;
            end % end for all patient
            
            app.TextLoading.Visible = 'off';
            app.Graph_AT.Visible = 'on';
            
            %% Button inable
            ImportButtonEnable(app,'on');

        end

        % Button pushed function: VersionButton
        function VersionButtonPushed(app, event)
            Import_version(app,app.Data);
            app.VersionButton.Enable = 'off';
        end

        % Menu selected function: OpenFolderMenu
        function OpenFolderMenuSelected(app, event)
            try
                delete(app.Tree.Children);
                path_root = uigetdir;
%                 path_root = 'D:\OneDrive - 바이랩\임상시험데이터\SNUH-OR';
%                 path_root = 'D:\OneDrive - 바이랩\임상시험데이터\AMC';
                key_file = '/**/SerialNumber.ini';
                key_path = '\Info';
                dir_root = dir([path_root key_file]);
            catch
               error('not found') ;
            end
            name_patient = [];
            idx_patient = 1;
            cnt_name_error = 1;
            
            if isempty(dir_root) == 0
                %% path info
                cnt_s = 1;
                for cnt = 1:length(dir_root)
                    % find session path w/o \Info folder
                    temp = strsplit(dir_root(cnt).folder(1:strfind(dir_root(cnt).folder,key_path)-1),'\');
                    
                    % find session name (pname + date + time)
                    temp = temp{end};
                    
                    % separate pname / date / time
                    idx_sep = strfind(temp,'_');
                    if ~(length(idx_sep) < 2)
                        try
                        session(cnt_s).path = dir_root(cnt).folder(1:strfind(dir_root(cnt).folder,key_path)-1);
                        session(cnt_s).time = temp(idx_sep(end)+1:end);
                        session(cnt_s).date = temp(idx_sep(end-1)+1:idx_sep(end)-1);
                        session(cnt_s).ID = temp(1:idx_sep(end-1)-1);

                        % check Data missing
                        if isfile([session(cnt_s).path '\RawData\' 'Pressure_0.bin']) && isfile([session(cnt_s).path '\RawData\' 'Setting_0.bin']) && ...
                                (isfile([session(cnt_s).path '\RawData\' 'EITImpedance_0.bin']) || isfile([session(cnt_s).path '\RawData\' 'EITGain_0.bin']) || isfile([session(cnt_s).path '\RawData\' 'EIT_0.bin']))
                            session(cnt_s).enable_data = 1;
                        else
                            session(cnt_s).enable_data = 0;
                        end
                        cnt_s = cnt_s + 1;
                        catch
                            session(cnt_s) = [];
                        end
                    else
                        cnt_name_error = cnt_name_error+1;
                        error_info.path{cnt_name_error} = dir_root(cnt);
                    end
                end
                
                %% data info
                for cnt = 1:length(session)
                    % patient info
                    try
                        fid = fopen(strcat(session(cnt).path,'\Info\Patientinfo.ini'));
                        info_p = textscan(fid,'%s');
                        info_p = info_p{1,1};
                        fclose(fid);
                        
                        for i = 1:length(info_p)
                            info_name = split(info_p{i} ,'=');
                            switch info_name{1}
                                case 'AGE'
                                    session(cnt).info_p.Age = str2double(info_p{i}(length('AGE')+2:end));
                                case 'GENDER'
                                    session(cnt).info_p.Gender = info_p{i}(length('GENDER')+2:end);
                                case 'WEIGHT'
                                    session(cnt).info_p.W = str2double(info_p{i}(length('WEIGHT')+2:end));
                                case 'HEIGHT'
                                    session(cnt).info_p.H = str2double(info_p{i}(length('HEIGHT')+2:end));
                                case 'FIRST_NAME'
                                    session(cnt).info_p.Name_first = info_p{i}(length('FIRST_NAME')+2:end);
                                case 'LAST_NAME'
                                    session(cnt).info_p.Name_last = info_p{i}(length('LAST_NAME')+2:end);   
                            end
                        end
                    catch
                        session(cnt).info_p.Age = 0;
                        session(cnt).info_p.Gender = '';
                        session(cnt).info_p.W = 0;
                        session(cnt).info_p.H = 0;
                        session(cnt).info_p.Name_first = '';
                        session(cnt).info_p.Name_last = '';
                    end
                    
                    % Version info
                    try
                        fid = fopen(strcat(session(cnt).path,'\Info\Version_All.ini'));
                        info_p = textscan(fid,'%s');
                        info_p = info_p{1,1};
                        fclose(fid);
                        
                        for i = 1:length(info_p)
                            info_name = split(info_p{i} ,'=');
                            switch info_name{1}
                                case 'DSP_FW'
                                    session(cnt).version.DSP_FW = info_p{i}(length('DSP_FW')+2:end);
                                case 'DSP_HW'
                                    session(cnt).version.DSP_HW = info_p{i}(length('DSP_HW')+2:end);
                                case 'MAC'
                                    session(cnt).version.MAC = info_p{i}(length('MAC')+2:end);
                                case 'MAIN_FPGA'
                                    session(cnt).version.MAIN_FPGA = info_p{i}(length('MAIN_FPGA')+2:end);
                                case 'MAIN_HW'
                                    session(cnt).version.MAIN_HW = info_p{i}(length('MAIN_HW')+2:end);
                                case 'SW'
                                    session(cnt).version.SW = info_p{i}(length('SW')+2:end);
                                case 'VM1_FPGA'
                                    session(cnt).version.VM1_FPGA = info_p{i}(length('VM1_FPGA')+2:end);
                                case 'VM2_FPGA'
                                    session(cnt).version.VM2_FPGA = info_p{i}(length('VM2_FPGA')+2:end);
                            end
                        end
                    catch
                        session(cnt).version.DSP_FW = -1;
                        session(cnt).version.DSP_HW = -1;
                        session(cnt).version.MAC = -1;
                        session(cnt).version.MAIN_FPGA = -1;
                        session(cnt).version.MAIN_HW = -1;
                        session(cnt).version.SW = -1;
                        session(cnt).version.VM1_FPGA = -1;
                        session(cnt).version.VM2_FPGA = -1;
                    end

                    % Duration
                    try
                        fid = fopen(strcat(session(cnt).path,'\Info\DurationTime.txt'));
                        info_p = textscan(fid,'%s');
                        info_p = info_p{1,1};
                        fclose(fid);
                        session(cnt).duration = (str2double(info_p{2})-str2double(info_p{1}))/1000/60/60;
                    catch
                        session(cnt).duration = 0;
                    end
                end
                
                %% Patient categorization
                for cnt = 1:length(session)
                    if cnt == 1
                        patient(1).idx_session = [cnt];
                        patient(1).ID = session(cnt).ID;
                        patient(1).duration = session(cnt).duration;
                        patient(1).path_root = path_root;
                        flag_stacked = 1;
                    else
                        for cnt_p = 1:length(patient)
                            if strcmp(patient(cnt_p).ID, session(cnt).ID)
                                patient(cnt_p).idx_session = [patient(cnt_p).idx_session cnt];
                                patient(cnt_p).duration = patient(cnt_p).duration + session(cnt).duration;
                                flag_stacked = 1;
                            end
                        end
                    end
                    if flag_stacked == 0
                        patient(cnt_p+1).idx_session = [cnt];
                        patient(cnt_p+1).ID = session(cnt).ID;
                        patient(cnt_p+1).duration = session(cnt).duration;
                        patient(cnt_p+1).path_root = path_root;
                    end
                    flag_stacked = 0;
                end
                
                for cnt_p = 1:length(patient)
                    tree_patient(cnt_p) = uitreenode(app.Tree, 'NodeData', patient(cnt_p).idx_session,...
                                                     'Text', patient(cnt_p).ID);
                    for cnt = 1:length(patient(cnt_p).idx_session)
                        uitreenode(tree_patient(cnt_p), 'NodeData', patient(cnt_p).idx_session(cnt), ...
                                   'Text',[session(patient(cnt_p).idx_session(cnt)).date '_' session(patient(cnt_p).idx_session(cnt)).time]);
                    end
                    %                     expand(tree_patient(cnt_p));
                end
                %                 session.{cnt}.
                app.Data.session = session;
                app.Data.patient = patient;
            else
                errordlg('Not found');
            end
            app.ImportButton.Enable = 'on';
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            try
                if isfield(app, 'Import_Option')
                    delete(app.Import_Option);
                end
                if isfield(app, 'Import_Version')
                    delete(app.Import_Version);
                end
                delete(app);
            catch
                delete(app);
            end
        end

        % Value changed function: SampleRateDropDown
        function SampleRateDropDownValueChanged(app, event)
            TableUpdate(app);
        end

        % Button pushed function: TableExportButton
        function TableExportButtonPushed(app, event)
            % Table csv로 출력부분
            [file,path] = uiputfile('*.xls');
            if isequal(file,0) || isequal(path,0)
               disp('User clicked Cancel.')
            else
               writetable(timetable2table(app.Table),[path file]);
               msgbox('Export Completed','','none');
            end
        end

        % Menu selected function: CaptureMenu
        function CaptureMenuSelected(app, event)
            % Graph 캡쳐
            [file,path] = uiputfile('*.png');
            if isequal(file,0) || isequal(path,0)
               disp('User clicked Cancel.')
            else
               exportgraphics(app.Graph_AT,[path file '.png']);
               msgbox('Export Completed','','none');
            end
        end

        % Menu selected function: Median7
        function Median7MenuSelected(app, event)
            for cnt = 1:length(app.FilterMenu.Children)
                app.FilterMenu.Children(cnt).Checked = 0;
            end
            app.FilterMenu.Children(strcmp({app.FilterMenu.Children(:).Text},'Median (7 cycle)')).Checked = 1;
            app.Opt.Graph.avg_num = 3;

            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
                TableUpdate(app);
            end
        end

        % Menu selected function: Median5
        function Median5MenuSelected(app, event)
            for cnt = 1:length(app.FilterMenu.Children)
                app.FilterMenu.Children(cnt).Checked = 0;
            end
            app.FilterMenu.Children(strcmp({app.FilterMenu.Children(:).Text},'Median (5 cycle)')).Checked = 1;
            app.Opt.Graph.avg_num = 2;

            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
                TableUpdate(app);
            end
        end

        % Menu selected function: Median3
        function Median3MenuSelected(app, event)
            for cnt = 1:length(app.FilterMenu.Children)
                app.FilterMenu.Children(cnt).Checked = 0;
            end
            app.FilterMenu.Children(strcmp({app.FilterMenu.Children(:).Text},'Median (3 cycle)')).Checked = 1;
            app.Opt.Graph.avg_num = 1;

            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
                TableUpdate(app);
            end
        end

        % Menu selected function: None
        function NoneMenuSelected(app, event)
            for cnt = 1:length(app.FilterMenu.Children)
                app.FilterMenu.Children(cnt).Checked = 0;
            end
            app.FilterMenu.Children(strcmp({app.FilterMenu.Children(:).Text},'None')).Checked = 1;
            app.Opt.Graph.avg_num = 0;

            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
                TableUpdate(app);
            end
        end

        % Menu selected function: dotMenu
        function dotMenuSelected(app, event)
            for cnt = 1:length(app.LineStyleMenu.Children)
                app.LineStyleMenu.Children(cnt).Checked = 0;
            end
            app.LineStyleMenu.Children(strcmp({app.LineStyleMenu.Children(:).Text},'dot (.)')).Checked = 1;
            app.Opt.Graph.marker_type = '.';
            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
            end
        end

        % Menu selected function: lineMenu
        function lineMenuSelected(app, event)
            for cnt = 1:length(app.LineStyleMenu.Children)
                app.LineStyleMenu.Children(cnt).Checked = 0;
            end
            app.LineStyleMenu.Children(strcmp({app.LineStyleMenu.Children(:).Text},'line (-)')).Checked = 1;
            app.Opt.Graph.marker_type = '-';
            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
            end
        end

        % Menu selected function: circleoMenu
        function circleoMenuSelected(app, event)
            for cnt = 1:length(app.LineStyleMenu.Children)
                app.LineStyleMenu.Children(cnt).Checked = 0;
            end
            app.LineStyleMenu.Children(strcmp({app.LineStyleMenu.Children(:).Text},'circle (o)')).Checked = 1;
            app.Opt.Graph.marker_type = 'o';
            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
            end
        end

        % Menu selected function: Size3
        function Size3Selected(app, event)
            for cnt = 1:length(app.MarkerSizeMenu.Children)
                app.MarkerSizeMenu.Children(cnt).Checked = 0;
            end
            app.MarkerSizeMenu.Children(strcmp({app.MarkerSizeMenu.Children(:).Text},'3')).Checked = 1;
            app.Opt.Graph.line_width = 3;
            app.Opt.Graph.marker_size = 10;
            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
            end
        end

        % Menu selected function: Size2
        function Size2Selected(app, event)
            for cnt = 1:length(app.MarkerSizeMenu.Children)
                app.MarkerSizeMenu.Children(cnt).Checked = 0;
            end
            app.MarkerSizeMenu.Children(strcmp({app.MarkerSizeMenu.Children(:).Text},'2')).Checked = 1;
            app.Opt.Graph.line_width = 2;
            app.Opt.Graph.marker_size = 7;
            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
            end
        end

        % Menu selected function: Size1
        function Size1Selected(app, event)
            for cnt = 1:length(app.MarkerSizeMenu.Children)
                app.MarkerSizeMenu.Children(cnt).Checked = 0;
            end
            app.MarkerSizeMenu.Children(strcmp({app.MarkerSizeMenu.Children(:).Text},'1')).Checked = 1;
            app.Opt.Graph.line_width = 1;
            app.Opt.Graph.marker_size = 4;
            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
            end
        end

        % Menu selected function: ESUMenu
        function ESUMenuSelected(app, event)
            app.ESUMenu.Checked = ~app.ESUMenu.Checked;
            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
                TableUpdate(app);
            end
        end

        % Menu selected function: dFRCMenu
        function dFRCMenuSelected(app, event)
            app.dFRCMenu.Checked = ~app.dFRCMenu.Checked;
            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
                TableUpdate(app);
            end
        end

        % Menu selected function: dRRMenu
        function dRRMenuSelected(app, event)
            app.dRRMenu.Checked = ~app.dRRMenu.Checked;
            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
                TableUpdate(app);
            end
        end

        % Menu selected function: dTVMenu
        function dTVMenuSelected(app, event)
            app.dTVMenu.Checked = ~app.dTVMenu.Checked;
            if ~(isempty(app.TV) && isempty(app.Wave))
                % draw graph, table
                PlotGraph(app);
                TableUpdate(app);
            end
        end

        % Menu selected function: showremovedMenu
        function showremovedMenuSelected(app, event)
            app.showremovedMenu.Checked = ~app.showremovedMenu.Checked;
            app.Opt.show_removed = app.showremovedMenu.Checked;
            PlotGraph(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataViewerUIFigure = uifigure('Visible', 'off');
            app.DataViewerUIFigure.Name = 'Data Viewer';
            app.DataViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @DataViewerUIFigureCloseRequest, true);
            app.Menu = uimenu(app.DataViewerUIFigure);
            app.Menu.Text = 'Menu';
            app.OpenFolderMenu = uimenu(app.Menu);
            app.OpenFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFolderMenuSelected, true);
            app.OpenFolderMenu.Text = 'Open Folder';
            app.OpenFileMenu = uimenu(app.Menu);
            app.OpenFileMenu.Enable = 'off';
            app.OpenFileMenu.Text = 'Open File';
            app.QuitMenu = uimenu(app.Menu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.GraphMenu = uimenu(app.DataViewerUIFigure);
            app.GraphMenu.Text = 'Graph';
            app.SQIMenu = uimenu(app.GraphMenu);
            app.SQIMenu.Checked = 'on';
            app.SQIMenu.Text = 'SQI';
            app.ESUMenu = uimenu(app.SQIMenu);
            app.ESUMenu.MenuSelectedFcn = createCallbackFcn(app, @ESUMenuSelected, true);
            app.ESUMenu.Checked = 'on';
            app.ESUMenu.Text = 'ESU';
            app.dFRCMenu = uimenu(app.SQIMenu);
            app.dFRCMenu.MenuSelectedFcn = createCallbackFcn(app, @dFRCMenuSelected, true);
            app.dFRCMenu.Checked = 'on';
            app.dFRCMenu.Text = 'dFRC';
            app.dRRMenu = uimenu(app.SQIMenu);
            app.dRRMenu.MenuSelectedFcn = createCallbackFcn(app, @dRRMenuSelected, true);
            app.dRRMenu.Checked = 'on';
            app.dRRMenu.Text = 'dRR';
            app.dTVMenu = uimenu(app.SQIMenu);
            app.dTVMenu.MenuSelectedFcn = createCallbackFcn(app, @dTVMenuSelected, true);
            app.dTVMenu.Checked = 'on';
            app.dTVMenu.Text = 'dTV';
            app.showremovedMenu = uimenu(app.SQIMenu);
            app.showremovedMenu.MenuSelectedFcn = createCallbackFcn(app, @showremovedMenuSelected, true);
            app.showremovedMenu.Text = 'show removed';
            app.FilterMenu = uimenu(app.GraphMenu);
            app.FilterMenu.Checked = 'on';
            app.FilterMenu.Text = 'Filter';
            app.Median7 = uimenu(app.FilterMenu);
            app.Median7.MenuSelectedFcn = createCallbackFcn(app, @Median7MenuSelected, true);
            app.Median7.Checked = 'on';
            app.Median7.Text = 'Median (7 cycle)';
            app.Median5 = uimenu(app.FilterMenu);
            app.Median5.MenuSelectedFcn = createCallbackFcn(app, @Median5MenuSelected, true);
            app.Median5.Text = 'Median (5 cycle)';
            app.Median3 = uimenu(app.FilterMenu);
            app.Median3.MenuSelectedFcn = createCallbackFcn(app, @Median3MenuSelected, true);
            app.Median3.Text = 'Median (3 cycle)';
            app.None = uimenu(app.FilterMenu);
            app.None.MenuSelectedFcn = createCallbackFcn(app, @NoneMenuSelected, true);
            app.None.Text = 'None';
            app.LineStyleMenu = uimenu(app.GraphMenu);
            app.LineStyleMenu.Text = 'LineStyle';
            app.dotMenu = uimenu(app.LineStyleMenu);
            app.dotMenu.MenuSelectedFcn = createCallbackFcn(app, @dotMenuSelected, true);
            app.dotMenu.Checked = 'on';
            app.dotMenu.Text = 'dot (.)';
            app.lineMenu = uimenu(app.LineStyleMenu);
            app.lineMenu.MenuSelectedFcn = createCallbackFcn(app, @lineMenuSelected, true);
            app.lineMenu.Text = 'line (-)';
            app.circleoMenu = uimenu(app.LineStyleMenu);
            app.circleoMenu.MenuSelectedFcn = createCallbackFcn(app, @circleoMenuSelected, true);
            app.circleoMenu.Text = 'circle (o)';
            app.MarkerSizeMenu = uimenu(app.GraphMenu);
            app.MarkerSizeMenu.Text = 'MarkerSize';
            app.Size3 = uimenu(app.MarkerSizeMenu);
            app.Size3.MenuSelectedFcn = createCallbackFcn(app, @Size3Selected, true);
            app.Size3.Text = '3';
            app.Size2 = uimenu(app.MarkerSizeMenu);
            app.Size2.MenuSelectedFcn = createCallbackFcn(app, @Size2Selected, true);
            app.Size2.Checked = 'on';
            app.Size2.Text = '2';
            app.Size1 = uimenu(app.MarkerSizeMenu);
            app.Size1.MenuSelectedFcn = createCallbackFcn(app, @Size1Selected, true);
            app.Size1.Text = '1';
            app.DataselectionMenu = uimenu(app.GraphMenu);
            app.DataselectionMenu.Enable = 'off';
            app.DataselectionMenu.Text = 'Data selection';
            app.CaptureMenu = uimenu(app.GraphMenu);
            app.CaptureMenu.MenuSelectedFcn = createCallbackFcn(app, @CaptureMenuSelected, true);
            app.CaptureMenu.Text = 'Capture';
            app.ImageMenu = uimenu(app.DataViewerUIFigure);
            app.ImageMenu.Text = 'Image';
            app.ScaleMenu = uimenu(app.ImageMenu);
            app.ScaleMenu.Enable = 'off';
            app.ScaleMenu.Text = 'Scale';
            app.Scale_Auto = uimenu(app.ScaleMenu);
            app.Scale_Auto.Checked = 'on';
            app.Scale_Auto.Text = 'Auto';
            app.Scale_1500mL = uimenu(app.ScaleMenu);
            app.Scale_1500mL.Text = '1500 mL';
            app.Scale_1000mL = uimenu(app.ScaleMenu);
            app.Scale_1000mL.Text = '1000 mL';
            app.Scale_500mL = uimenu(app.ScaleMenu);
            app.Scale_500mL.Text = '500 mL';
            app.Scale_200mL = uimenu(app.ScaleMenu);
            app.Scale_200mL.Text = '200 mL';
            app.Scale_100mL = uimenu(app.ScaleMenu);
            app.Scale_100mL.Text = '100 mL';
            app.ColormapMenu = uimenu(app.ImageMenu);
            app.ColormapMenu.Enable = 'off';
            app.ColormapMenu.Text = 'Colormap';
            app.BPMenu = uimenu(app.ColormapMenu);
            app.BPMenu.Checked = 'on';
            app.BPMenu.Text = 'B/P';
            app.BRMenu = uimenu(app.ColormapMenu);
            app.BRMenu.Text = 'B/R';
            app.TabGroup = uitabgroup(app.DataViewerUIFigure);
            app.DataViewTab = uitab(app.TabGroup);
            app.DataViewTab.Title = 'Data View';
            app.PatientInfoPanel = uipanel(app.DataViewTab);
            app.PatientInfoPanel.Title = 'Patient Info';
            app.NameEditFieldLabel = uilabel(app.PatientInfoPanel);
            app.NameEditFieldLabel.Enable = 'off';
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PatientInfoPanel, 'text');
            app.NameEditField.Editable = 'off';
            app.NameEditField.Enable = 'off';
            app.AgeEditFieldLabel = uilabel(app.PatientInfoPanel);
            app.AgeEditFieldLabel.Enable = 'off';
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.PatientInfoPanel, 'numeric');
            app.AgeEditField.Editable = 'off';
            app.AgeEditField.Enable = 'off';
            app.WeightEditFieldLabel = uilabel(app.PatientInfoPanel);
            app.WeightEditFieldLabel.Enable = 'off';
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.PatientInfoPanel, 'numeric');
            app.WeightEditField.Editable = 'off';
            app.WeightEditField.Enable = 'off';
            app.HeightEditFieldLabel = uilabel(app.PatientInfoPanel);
            app.HeightEditFieldLabel.Enable = 'off';
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.PatientInfoPanel, 'numeric');
            app.HeightEditField.Editable = 'off';
            app.HeightEditField.Enable = 'off';
            app.GenderEditFieldLabel = uilabel(app.PatientInfoPanel);
            app.GenderEditFieldLabel.Enable = 'off';
            app.GenderEditFieldLabel.Text = 'Gender';
            app.GenderEditField = uieditfield(app.PatientInfoPanel, 'text');
            app.GenderEditField.Editable = 'off';
            app.GenderEditField.Enable = 'off';
            app.DataInfoPanel = uipanel(app.DataViewTab);
            app.DataInfoPanel.Title = 'Data Info';
            app.DurationEditFieldLabel = uilabel(app.DataInfoPanel);
            app.DurationEditFieldLabel.Enable = 'off';
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.DataInfoPanel, 'text');
            app.DurationEditField.Editable = 'off';
            app.DurationEditField.Enable = 'off';
            app.TypeDropDownLabel = uilabel(app.DataInfoPanel);
            app.TypeDropDownLabel.Enable = 'off';
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.DataInfoPanel);
            app.TypeDropDown.Items = {'AirTom', 'Hemovista', 'Other'};
            app.TypeDropDown.Enable = 'off';
            app.TypeDropDown.Value = 'AirTom';
            app.VersionButton = uibutton(app.DataInfoPanel, 'push');
            app.VersionButton.ButtonPushedFcn = createCallbackFcn(app, @VersionButtonPushed, true);
            app.VersionButton.Enable = 'off';
            app.VersionButton.Text = 'Version';
            app.DateEditFieldLabel = uilabel(app.DataInfoPanel);
            app.DateEditFieldLabel.Enable = 'off';
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.DataInfoPanel, 'text');
            app.DateEditField.Editable = 'off';
            app.DateEditField.Enable = 'off';
            app.OptionButton = uibutton(app.DataInfoPanel, 'push');
            app.OptionButton.ButtonPushedFcn = createCallbackFcn(app, @OptionButtonPushed, true);
            app.OptionButton.Enable = 'off';
            app.OptionButton.Text = 'Option';
            app.Enable_Data = uilamp(app.DataInfoPanel);
            app.Tree = uitree(app.DataViewTab);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ImportButton = uibutton(app.DataViewTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = '>>';
            app.Graph_AT = uipanel(app.DataViewTab);
            app.Graph_AT.Visible = 'off';
            app.TextLoading = uieditfield(app.DataViewTab, 'text');
            app.TextLoading.Editable = 'off';
            app.TextLoading.Enable = 'off';
            app.NumericViewTab = uitab(app.TabGroup);
            app.NumericViewTab.Title = 'Numeric View';
            app.UITable = uitable(app.NumericViewTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.SampleRateDropDownLabel = uilabel(app.NumericViewTab);
            app.SampleRateDropDownLabel.Text = 'Sample Rate';
            app.SampleRateDropDown = uidropdown(app.NumericViewTab);
            app.SampleRateDropDown.Items = {'Breath by breath', '1 min (average)', '10 min (average)', '30 min (average)', '1 hour (average)', 'Selection'};
            app.SampleRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleRateDropDownValueChanged, true);
            app.SampleRateDropDown.Value = 'Breath by breath';
            app.TableExportButton = uibutton(app.NumericViewTab, 'push');
            app.TableExportButton.ButtonPushedFcn = createCallbackFcn(app, @TableExportButtonPushed, true);
            app.TableExportButton.Text = 'Export';
            app.ImageViewTab = uitab(app.TabGroup);
            app.ImageViewTab.Title = 'Image View';
            app.Image = uiimage(app.ImageViewTab);
            app.PEEPdeterminationTab = uitab(app.TabGroup);
            app.PEEPdeterminationTab.Title = 'PEEP determination';
            app.DatePickerLabel = uilabel(app.PEEPdeterminationTab);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.PEEPdeterminationTab);
            % Show the figure after all components are created
            app.DataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataViewerUIFigure)
        end
    end
end