classdef Import_B2B_ver1_20221030 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AirTomDataViewer          matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenFolderMenu            matlab.ui.container.Menu
        OpenFileMenu              matlab.ui.container.Menu
        QuitMenu                  matlab.ui.container.Menu
        OutlierMenu               matlab.ui.container.Menu
        BaselineMenu              matlab.ui.container.Menu
        TVMenu                    matlab.ui.container.Menu
        RRMenu                    matlab.ui.container.Menu
        ESUMenu                   matlab.ui.container.Menu
        ShowOutliersMenu          matlab.ui.container.Menu
        GraphMenu                 matlab.ui.container.Menu
        FilterMenu                matlab.ui.container.Menu
        Median7                   matlab.ui.container.Menu
        Median5                   matlab.ui.container.Menu
        Median3                   matlab.ui.container.Menu
        None                      matlab.ui.container.Menu
        LineStyleMenu             matlab.ui.container.Menu
        dotMenu                   matlab.ui.container.Menu
        lineMenu                  matlab.ui.container.Menu
        circleoMenu               matlab.ui.container.Menu
        MarkerSizeMenu            matlab.ui.container.Menu
        Size3                     matlab.ui.container.Menu
        Size2                     matlab.ui.container.Menu
        Size1                     matlab.ui.container.Menu
        DataselectionMenu         matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        GraphViewTab              matlab.ui.container.Tab
        GridLayout_Graph          matlab.ui.container.GridLayout
        Logo1                     matlab.ui.control.Image
        Graph_AT                  matlab.ui.container.Panel
        Panel_Info1               matlab.ui.container.Panel
        IDEditFieldLabel          matlab.ui.control.Label
        IDEditField_1             matlab.ui.control.EditField
        HeightLabel               matlab.ui.control.Label
        HeightStrField_1          matlab.ui.control.EditField
        WeightEditField_3Label    matlab.ui.control.Label
        WeightStrField_1          matlab.ui.control.EditField
        AgeEditField_3Label       matlab.ui.control.Label
        AgeStrField_1             matlab.ui.control.EditField
        GenderEditField_3Label    matlab.ui.control.Label
        GenderStrField_1          matlab.ui.control.EditField
        DateLabel                 matlab.ui.control.Label
        DateStrField_1            matlab.ui.control.EditField
        TimeLabel                 matlab.ui.control.Label
        TimeStrField_1            matlab.ui.control.EditField
        GraphExportButton         matlab.ui.control.Button
        ZoomResetButton           matlab.ui.control.Button
        GraphHidePanel            matlab.ui.container.Panel
        LoadingImage_1            matlab.ui.control.Image
        NumericViewTab            matlab.ui.container.Tab
        GridLayout_Table          matlab.ui.container.GridLayout
        TableExportButton         matlab.ui.control.Button
        SampleRateDropDown        matlab.ui.control.DropDown
        Logo2                     matlab.ui.control.Image
        UITable                   matlab.ui.control.Table
        Panel_Info2               matlab.ui.container.Panel
        IDEditField_2Label        matlab.ui.control.Label
        IDEditField_2             matlab.ui.control.EditField
        HeightLabel_2             matlab.ui.control.Label
        HeightStrField_2          matlab.ui.control.EditField
        WeightEditField_3Label_2  matlab.ui.control.Label
        WeightStrField_2          matlab.ui.control.EditField
        AgeEditField_3Label_2     matlab.ui.control.Label
        AgeStrField_2             matlab.ui.control.EditField
        GenderEditField_3Label_2  matlab.ui.control.Label
        GenderStrField_2          matlab.ui.control.EditField
        DateLabel_2               matlab.ui.control.Label
        DateStrField_2            matlab.ui.control.EditField
        TimeLabel_2               matlab.ui.control.Label
        TimeStrField_2            matlab.ui.control.EditField
        LoadingImage_2            matlab.ui.control.Image
    end

    
    properties (Access = private)
        Data; % Description
        B2B % Description
        Wave % Description
        B2BTable % Description
        B2BTable_outlier % Description
        Opt % Description
        Graph % Description
        mode % AT / HV
    end
    
    methods (Access = private)
        function AllButtonEnable(app,isenable)
            if strcmp(isenable, 'on')
                app.GraphExportButton.Enable = 'on';
                app.TableExportButton.Enable = 'on';
                app.SampleRateDropDown.Enable = 'on';
                app.ZoomResetButton.Enable = 'on';
            else
                app.GraphExportButton.Enable = 'off';
                app.TableExportButton.Enable = 'off';
                app.SampleRateDropDown.Enable = 'off';
                app.ZoomResetButton.Enable = 'off';
            end
            drawnow;
        end
        
        function LoagingImage(app,isenable)
            if strcmp(isenable, 'on')
                app.LoadingImage_1.Visible = 'on';
                app.LoadingImage_2.Visible = 'on';
            else
                app.LoadingImage_1.Visible = 'off';
                app.LoadingImage_2.Visible = 'off';
            end
            drawnow;
        end
        
        function PatientInfoUpdate(app)
            app.AgeStrField_1.Value = num2str(app.Data.session.info_p.Age);
            app.WeightStrField_1.Value = strcat(num2str(app.Data.session.info_p.W),' kg');
            app.HeightStrField_1.Value = strcat(num2str(app.Data.session.info_p.H),' cm');
            app.GenderStrField_1.Value = app.Data.session.info_p.Gender;
            app.IDEditField_1.Value = app.Data.session.info_p.ID;
            app.DateStrField_1.Value = datestr(app.Wave.t_hms(1),'yyyy.mm.dd');
            app.TimeStrField_1.Value = string(app.Wave.t_hms(end)-app.Wave.t_hms(1));
            app.AgeStrField_1.Enable = 'on';
            app.WeightStrField_1.Enable = 'on';
            app.HeightStrField_1.Enable = 'on';
            app.GenderStrField_1.Enable = 'on';
            app.IDEditField_1.Enable = 'on';
            app.DateStrField_1.Enable = 'on';
            app.TimeStrField_1.Enable = 'on';
                                    
            app.AgeStrField_2.Value = num2str(app.Data.session.info_p.Age);
            app.WeightStrField_2.Value = strcat(num2str(app.Data.session.info_p.W),' kg');
            app.HeightStrField_2.Value = strcat(num2str(app.Data.session.info_p.H),' cm');
            app.GenderStrField_2.Value = app.Data.session.info_p.Gender;
            app.IDEditField_2.Value = app.Data.session.info_p.ID;
            app.DateStrField_2.Value = datestr(app.Wave.t_hms(1),'yyyy.mm.dd');
            app.TimeStrField_2.Value = string(app.Wave.t_hms(end)-app.Wave.t_hms(1));
            app.AgeStrField_2.Enable = 'on';
            app.WeightStrField_2.Enable = 'on';
            app.HeightStrField_2.Enable = 'on';
            app.GenderStrField_2.Enable = 'on';
            app.IDEditField_2.Enable = 'on';
            app.DateStrField_2.Enable = 'on';
            app.TimeStrField_2.Enable = 'on';
        end
        
        function B2BTableUpdate(app)
            %% SQI
            sqi_total = true(length(app.B2B),1);
            if app.BaselineMenu.Checked
                sqi_total = sqi_total & [app.B2B.sqi_dFRC]';
            end
            if app.RRMenu.Checked
                sqi_total = sqi_total & [app.B2B.sqi_dRR]';
            end
            if app.TVMenu.Checked
                sqi_total = sqi_total & [app.B2B.sqi_dTV]';
            end
            if app.ESUMenu.Checked
                sqi_total = sqi_total & [app.B2B.sqi_ESU]'; % need check
            end
%             if app.ESUMenu.Checked
%                 sqi_total = sqi_total & [app.B2B.sqi_ESU]';
%             end

            if sum(sqi_total) == 0 % avoid signal []
                sqi_total(1) = true;
            elseif sum(~sqi_total) == 0 % if SQI 0 does not exist -> show_removed disable
                app.Opt.show_removed = false;
            end
            
            % Gen file
            TV_Table_name = ["Time", "TVi", "TVe", "MV", "TVi_eit", ...
                             "TVe_eit", "MV_eit", "RR", "I:E", ...
                             "PIP", "PEEP", "dP", "Cdyn", "dFRC", "sdRVD", ...
                             "GI", "CoVx", "CoVy"];
            app.B2BTable = timetable([app.B2B(sqi_total).t_insp]', ...
                movmedian([app.B2B(sqi_total).TVi],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).TVe],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).MV],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).TVi_eit],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).TVe_eit],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).MV_eit],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).RR],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).IE],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).PIP],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).PEEP],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).dP],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).Cdyn],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).dFRC],[1 1]*app.Opt.Graph.avg_num)',  ...
                movmedian([app.B2B(sqi_total).sdRVD],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).GI],[1 1]*app.Opt.Graph.avg_num)', ...
                movmedian([app.B2B(sqi_total).CoVx],[1 1]*app.Opt.Graph.avg_num)'*100, ...
                movmedian([app.B2B(sqi_total).CoVy],[1 1]*app.Opt.Graph.avg_num)'*100);
            app.B2BTable.Properties.VariableNames = TV_Table_name(2:end);
            
            if app.Opt.show_removed == true % outlier data save for show removed
                app.B2BTable_outlier = timetable([app.B2B(~sqi_total).t_insp]', ...
                    [app.B2B(~sqi_total).TVi]', ...
                    [app.B2B(~sqi_total).TVe]', ...
                    [app.B2B(~sqi_total).MV]', ...
                    [app.B2B(~sqi_total).TVi_eit]', ...
                    [app.B2B(~sqi_total).TVe_eit]', ...
                    [app.B2B(~sqi_total).MV_eit]', ...
                    [app.B2B(~sqi_total).RR]', ...
                    [app.B2B(~sqi_total).IE]', ...
                    [app.B2B(~sqi_total).PIP]', ...
                    [app.B2B(~sqi_total).PEEP]', ...
                    [app.B2B(~sqi_total).dP]', ...
                    [app.B2B(~sqi_total).Cdyn]', ...
                    [app.B2B(~sqi_total).dFRC]',  ...
                    [app.B2B(~sqi_total).sdRVD]', ...
                    [app.B2B(~sqi_total).GI]', ...
                    [app.B2B(~sqi_total).CoVx]'*100, ...
                    [app.B2B(~sqi_total).CoVy]'*100);
                app.B2BTable_outlier.Properties.VariableNames = TV_Table_name(2:end);
            else
                app.B2BTable_outlier = []; % if outlier data is nothing
            end
        end
        
        function h = UIGraphUpdate(app)
            %% plot propertys
            LoagingImage(app,'on');
            app.GraphHidePanel.Visible = 'on'; 
            app.Graph_AT.Visible = 'off'; % avoid graph blinking

            line_width = app.Opt.Graph.line_width;
            marker_type = app.Opt.Graph.marker_type;
            marker_size = app.Opt.Graph.marker_size;
            label_size = app.Opt.Graph.label_size;
            label_rotation = 0;
            label_verticalalignment = 'middle';
            
            if strcmp(marker_type,'o')
                marker_size = marker_size/2;
            end
            removedmarker_size = app.Opt.Graph.marker_size/3;

            % Gen file
            B2BTableUpdate(app);
              
            %% Display
            if app.Data.enPaw == true
                cnt_fig = 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); plot(h(cnt_fig),app.Wave.t_hms(1:app.Opt.Graph.wave_skip_num:end),app.Wave.RVS(1:app.Opt.Graph.wave_skip_num:end),'linewidth',line_width,'color',[0.00,0.45,0.74]); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'RVS', '[mL]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.TVi,marker_type,'color',[0 0 0],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.TVi,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'TV',['[' app.Data.unit_TVeit ']']},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.dP,marker_type,'color',[0.3010 0.7450 0.9330],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.dP,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'{\Delta}P','[cmH_2O]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.Cdyn,marker_type,'color',[1 0 1],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.Cdyn,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'Cdyn','[mL/cmH_2O]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.PEEP,marker_type,'color',[0 1 1],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.PEEP,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'PEEP','[cmH_2O]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.GI,marker_type,'color',[0.9290 0.6940 0.1250],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % ylim([0.75 1.1]);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.GI,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'GI','[AU]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.sdRVD,marker_type,'color',[0.4940 0.1840 0.5560],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0,'margin',1.2);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.sdRVD,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'SD(RVD)','[ms]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig),"off"); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.CoVx,marker_type,'color',[0.6350 0.0780 0.1840],'linewidth',line_width,'MarkerSize',marker_size); hold(h(cnt_fig),"on");
                yyaxis(h(cnt_fig),'right'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.CoVy,marker_type,'color',[0.4660 0.6740 0.1880],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'YColor','k'); yline(h(cnt_fig),50,'k');
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.CoVx,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.CoVy,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'CoVxy','[%]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'YTickLabel',[]);
    
                app.Graph = h;
                temp2 = linspace(0.88, 0.05, 8);
                for cnt_fig = 1:length(app.Graph) % subplot reposition (is work any monitor resolution?)
                    app.Graph(cnt_fig).Position(1) = 0.07;
                    app.Graph(cnt_fig).Position(2) = temp2(cnt_fig);
                    app.Graph(cnt_fig).Position(3) = 0.88;
                    app.Graph(cnt_fig).Position(4) = 0.09;
    %                 app.Graph(cnt_fig).YLabel.Position(1) = 0.31;
                end
    
                linkaxes(app.Graph,'x'); xlim(app.Graph(cnt_fig),[app.Wave.t_hms(1) app.Wave.t_hms(end)]); % time axis rescale
                drawnow;
            elseif app.Data.enPaw == false
                cnt_fig = 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); plot(h(cnt_fig),app.Wave.t_hms(1:app.Opt.Graph.wave_skip_num:end),app.Wave.RVS(1:app.Opt.Graph.wave_skip_num:end),'linewidth',line_width,'color',[0.00,0.45,0.74]); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'RVS', ['[' app.Data.unit_TVeit ']']},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.TVi_eit,marker_type,'color',[0 0 0],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.TVi,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'TV',['[' app.Data.unit_TVeit ']']},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.RR,marker_type,'color',[0.3010 0.7450 0.9330],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.dP,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'RR','[BPM]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.MV_eit,marker_type,'color',[1 0 1],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.Cdyn,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'MV','[/min]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.RR./app.B2BTable.TVi_eit*1000,marker_type,'color',[0 1 1],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.PEEP,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'RSBI','[/L]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.GI,marker_type,'color',[0.9290 0.6940 0.1250],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % ylim([0.75 1.1]);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.GI,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'GI','[AU]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig), 'off'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.sdRVD,marker_type,'color',[0.4940 0.1840 0.5560],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'XtickLabel',[],'YColor','k'); % autoYlim('min',0,'margin',1.2);
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.sdRVD,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'SD(RVD)','[ms]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'XtickLabel',[],'YTick',[],'YTickLabel',[]);
                
                cnt_fig = cnt_fig + 1;
                h(cnt_fig) = subplot(8,1,cnt_fig,'Parent',app.Graph_AT);
                yyaxis(h(cnt_fig),'right'); hold(h(cnt_fig),"off"); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.CoVx,marker_type,'color',[0.6350 0.0780 0.1840],'linewidth',line_width,'MarkerSize',marker_size); hold(h(cnt_fig),"on");
                yyaxis(h(cnt_fig),'right'); plot(h(cnt_fig),app.B2BTable.Time,app.B2BTable.CoVy,marker_type,'color',[0.4660 0.6740 0.1880],'linewidth',line_width,'MarkerSize',marker_size); set(h(cnt_fig),'YColor','k'); yline(h(cnt_fig),50,'k');
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.CoVx,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                if app.Opt.show_removed == true; hold(h(cnt_fig), 'on'); plot(h(cnt_fig),app.B2BTable_outlier.Time,app.B2BTable_outlier.CoVy,'x','color',[0.7 0.7 0.7],'linewidth',line_width,'MarkerSize',removedmarker_size); end
                yyaxis(h(cnt_fig),'left'); ylabel(h(cnt_fig),{'CoVxy','[%]'},'FontWeight','bold','Color','k','fontsize',label_size,'VerticalAlignment',label_verticalalignment,'HorizontalAlignment','right','Rotation',label_rotation); set(h(cnt_fig),'YTickLabel',[]);
    
                app.Graph = h;
                temp2 = linspace(0.88, 0.05, 8);
                for cnt_fig = 1:length(app.Graph) % subplot reposition (is work any monitor resolution?)
                    app.Graph(cnt_fig).Position(1) = 0.07;
                    app.Graph(cnt_fig).Position(2) = temp2(cnt_fig);
                    app.Graph(cnt_fig).Position(3) = 0.88;
                    app.Graph(cnt_fig).Position(4) = 0.09;
    %                 app.Graph(cnt_fig).YLabel.Position(1) = 0.31;
                end
    
                linkaxes(app.Graph,'x'); xlim(app.Graph(cnt_fig),[app.Wave.t_hms(1) app.Wave.t_hms(end)]); % time axis rescale
                drawnow;
            end
            app.Graph_AT.Visible = 'on';
            app.GraphHidePanel.Visible = 'off';
            LoagingImage(app,'off');
        end
        
        function UITableUpdate(app)
            B2BTableUpdate(app);
            
            switch app.SampleRateDropDown.Value % time resampling
                case '1 min (average)'
                    time_start = dateshift(app.B2BTable.Time(1), 'start', 'minute');
                    time_end = dateshift(app.B2BTable.Time(end), 'end', 'minute');
                    time_resample = time_start:minutes(1):time_end;
                    temp_table = retime(app.B2BTable,time_resample,'mean');
                    temp_table(isnan(table2array(temp_table(:,1))),:) = [];
                case '10 min (average)'
                    time_start = dateshift(app.B2BTable.Time(1), 'start', 'minute');
                    time_end = dateshift(app.B2BTable.Time(end), 'end', 'minute');
                    time_resample = time_start:minutes(10):time_end;
                    temp_table = retime(app.B2BTable,time_resample,'mean');
                    temp_table(isnan(table2array(temp_table(:,1))),:) = [];
                case '30 min (average)'
                    time_start = dateshift(app.B2BTable.Time(1), 'start', 'minute');
                    time_end = dateshift(app.B2BTable.Time(end), 'end', 'minute');
                    time_resample = time_start:minutes(30):time_end;
                    temp_table = retime(app.B2BTable,time_resample,'mean');
                    temp_table(isnan(table2array(temp_table(:,1))),:) = [];
                case '1 hour (average)'
                    time_start = dateshift(app.B2BTable.Time(1), 'start', 'minute');
                    time_end = dateshift(app.B2BTable.Time(end), 'end', 'minute');
                    time_resample = time_start:hours(1):time_end;
                    temp_table = retime(app.B2BTable,time_resample,'mean');
                    temp_table(isnan(table2array(temp_table(:,1))),:) = [];
                case 'Selection'
                    temp_table = app.B2BTable;
                    %                     time_resample = time vector
                    %                     temp_table = retime(temp_table,time_resample,'mean');
                    %                     temp_table(isnan(table2array(temp_table(:,1))),:) = [];
                otherwise
                    temp_table = app.B2BTable;
            end
            
            %% UITable update
            TV_Table_name_withunit = ["Time", "TVi [mL]", "TVe [mL]", "MV [L]", ...
                                      strcat("TVi_eit [", app.Data.unit_TVeit, "]"), ...
                                      strcat("TVe_eit [", app.Data.unit_TVeit, "]"), ...
                                      strcat("MV_eit [", app.Data.unit_TVeit, "]"), ...
                                      "RR [bpm]", "I:E [ratio]", ...
                                      "PIP [cmH2O]", "PEEP [cmH2O]", "dP [cmH2O]", ...
                                      "Cdyn [mL/cmH2O]", "dFRC [mL]", "sdRVD [ms]", "GI [AU]", "CoVx [%]", "CoVy [%]"];
            app.UITable.Data = timetable2table(temp_table);
            app.UITable.ColumnName = TV_Table_name_withunit;
            
            app.UITable.Enable = "on";
            app.UITable.Visible = "on";
        end
        
        function BackgroundColor(app,ver)
            switch ver
                case 'AT'
                    Color_Back = [0.00, 0.35, 0.60];
                case 'HV'
                    Color_Back = [0.65, 0.1, 0.2];
            end
            
            try
                % GraphView Tap
                app.GraphViewTab.BackgroundColor = Color_Back;
                app.Panel_Info1.BackgroundColor = Color_Back;
                app.IDEditField_1.BackgroundColor = Color_Back;
                app.DateStrField_1.BackgroundColor = Color_Back;
                app.TimeStrField_1.BackgroundColor = Color_Back;
                app.HeightStrField_1.BackgroundColor = Color_Back;
                app.WeightStrField_1.BackgroundColor = Color_Back;
                app.AgeStrField_1.BackgroundColor = Color_Back;
                app.GenderStrField_1.BackgroundColor = Color_Back;
                
                % NumericView Tap
                app.NumericViewTab.BackgroundColor = Color_Back;
                app.Panel_Info2.BackgroundColor = Color_Back;
                app.IDEditField_2.BackgroundColor = Color_Back;
                app.DateStrField_2.BackgroundColor = Color_Back;
                app.TimeStrField_2.BackgroundColor = Color_Back;
                app.HeightStrField_2.BackgroundColor = Color_Back;
                app.WeightStrField_2.BackgroundColor = Color_Back;
                app.AgeStrField_2.BackgroundColor = Color_Back;
                app.GenderStrField_2.BackgroundColor = Color_Back;
                
%                 % Image Tap
%                 app.ImageViewTab.BackgroundColor = Color_Back;
%                 
%                 % PEEP Tap
%                 app.PEEPdeterminationTab.BackgroundColor = Color_Back;
                
                % GridColor (above only 2020b ver)
                app.GridLayout_Graph.BackgroundColor = Color_Back;
                app.GridLayout_Table.BackgroundColor = Color_Back;
%                 app.GridLayout_Image.BackgroundColor = Color_Back;
%                 app.GridLayout_PEEP.BackgroundColor = Color_Back;
                
            catch
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.mode = 'AT';
            BackgroundColor(app,app.mode)
            
            app.Data.session = [];
            app.Data.patient = [];
            app.Data.error_info = [];

            % graph
            app.Opt.Graph.avg_num = 3;
            app.Opt.Graph.wave_skip_num = 10;
            app.Opt.Graph.label_size = 9;
            app.Opt.Graph.line_width = 2;
            app.Opt.Graph.marker_size = 7;
            app.Opt.Graph.marker_type = '.';
            app.Opt.Comm.color_back_AT = [0 0.35 0.6]; % RGB code #005999
%             app.Graph_AT.BackgroundColor = app.Opt.Comm.color_back_AT;
            % SQI
%             app.Opt.sqi_dRR = 0.15;
%             app.Opt.sqi_TV = [10 2000];
            app.Opt.show_removed = app.ShowOutliersMenu.Checked;
                        
            %% window resize
            monit_size = get(0, 'MonitorPositions') ;
            monit_size = monit_size(1,:);
            window_size = app.AirTomDataViewer.Position;
            if monit_size(4) < sum(window_size([2 4])) % check over height
                resize_ratio = (monit_size(4)-window_size(2)*2.5)/window_size(4);
                app.AirTomDataViewer.Position(3:4) = resize_ratio * app.AirTomDataViewer.Position(3:4);
            end
            window_size = app.AirTomDataViewer.Position;
            if monit_size(3) < sum(window_size([1 3])) % check over width
                resize_ratio = (monit_size(3)-window_size(1)*2.5)/window_size(3);
                app.AirTomDataViewer.Position(3:4) = resize_ratio * app.AirTomDataViewer.Position(3:4);
            end
        end

        % Menu selected function: OpenFolderMenu
        function OpenFolderMenuSelected(app, event)
            try
                path_root = uigetdir;
%                 path_root = 'D:\OneDrive\2. Project\01. Research\EIT_Viewer\TestData\HV\aa_20220916_144114';
%                 path_root = 'D:\OneDrive\2. Project\01. Research\EIT_Viewer\TestData\AT\Yoo_20220830_110110';
%                 path_root = 'H:\_bilab\Yoo_20220831_105052';
%                 path_root = 'D:\OneDrive\2. Project\01. Research\Bilab work\7. newTVData\20220829_addROI_1';
%                 path_root = 'H:\_bilab\Yoo_20220831_105052';

                % SNU_NICU
%                 path_root = 'C:\Users\Administrator\Downloads\SNUH-NICU\SNUH-NICU\00_20221027_120853';
%                 path_root = 'C:\Users\Administrator\Downloads\SNUH-NICU\SNUH-NICU\80733298_20221024_112206';
%                 path_root = 'C:\Users\Administrator\Downloads\SNUH-NICU\SNUH-NICU\80733298_20221024_164835';
%                 path_root = 'D:\OneDrive - 바이랩\임상시험데이터\SNUH-Child\Child\미분류\80882239_20221017_092035\RawData';
%                 path_root = 'D:\OneDrive - 바이랩\임상시험데이터\SNUH-NICU\Test2_20221017_144937';
                

                if isempty(path_root)
                   error('folder not selected');
                end
                
                % move rootpath from inside
                if strcmp(path_root(end-8:end),'Parameter')
                    path_root(end-9:end) = [];
                elseif strcmp(path_root(end-6:end),'RawData')
                    path_root(end-7:end) = [];
                end
                
                % check Raw & Param files
                app.Data.session.exist_raw = false;
                app.Data.session.exist_b2b = false;
                if exist([path_root '\RawData\Setting_0.bin'],'file') == 2
                    app.Data.session.exist_raw = true;
                end
                if exist([path_root '\Parameter\GraphAndSensor_0.bin'],'file') == 2
                    app.Data.session.exist_b2b = true;
                end
                
                % set paths
                app.Data.session.path_root = path_root;
                app.Data.session.path_info = strcat(path_root, '\Info');
                app.Data.session.path_param = strcat(path_root, '\Parameter');
                app.Data.session.path_raw = strcat(path_root, '\RawData');
                
                % patient ID
                temp = strsplit(app.Data.session.path_root,'\');
                temp2 = strsplit(temp{end},'_');
                if length(temp2)>2
                    app.Data.session.info_p.ID = strcat(temp2{1:end-2});
                else
                    app.Data.session.info_p.ID = temp{end};
                end
                clear temp temp2;
            catch
                msgbox('data not found','','none');
                error('data not found') ;
            end
            
%             try
                LoagingImage(app,'on');
                if isempty(path_root) == 0
                    % patient info
                    try
                        fid = fopen(strcat(app.Data.session.path_info,'\Patientinfo.ini'));
                        info_p = textscan(fid,'%s');
                        info_p = info_p{1,1};
                        fclose(fid);
                        
                        for i = 1:length(info_p)
                            info_name = split(info_p{i} ,'=');
                            switch info_name{1}
                                case 'AGE'
                                    app.Data.session.info_p.Age = str2double(info_p{i}(length('AGE')+2:end));
                                case 'GENDER'
                                    app.Data.session.info_p.Gender = info_p{i}(length('GENDER')+2:end);
                                case 'WEIGHT'
                                    app.Data.session.info_p.W = str2double(info_p{i}(length('WEIGHT')+2:end));
                                case 'HEIGHT'
                                    app.Data.session.info_p.H = str2double(info_p{i}(length('HEIGHT')+2:end));
                                case 'FIRST_NAME'
                                    app.Data.session.info_p.Name_first = info_p{i}(length('FIRST_NAME')+2:end);
                                case 'LAST_NAME'
                                    app.Data.session.info_p.Name_last = info_p{i}(length('LAST_NAME')+2:end);
                            end
                        end
                    catch
                        app.Data.session.info_p.Age = 0;
                        app.Data.session.info_p.Gender = '';
                        app.Data.session.info_p.W = 0;
                        app.Data.session.info_p.H = 0;
                        app.Data.session.info_p.Name_first = '';
                        app.Data.session.info_p.Name_last = '';
                    end
                    
                    % Version info
                    try
                        fid = fopen(strcat(app.Data.session.path_info,'\Version_All.ini'));
                        info_p = textscan(fid,'%s');
                        info_p = info_p{1,1};
                        fclose(fid);
                        
                        for i = 1:length(info_p)
                            info_name = split(info_p{i} ,'=');
                            switch info_name{1}
                                case 'DSP_FW'
                                    app.Data.session.version.DSP_FW = info_p{i}(length('DSP_FW')+2:end);
                                case 'DSP_HW'
                                    app.Data.session.version.DSP_HW = info_p{i}(length('DSP_HW')+2:end);
                                case 'MAC'
                                    app.Data.session.version.MAC = info_p{i}(length('MAC')+2:end);
                                case 'MAIN_FPGA'
                                    app.Data.session.version.MAIN_FPGA = info_p{i}(length('MAIN_FPGA')+2:end);
                                case 'MAIN_HW'
                                    app.Data.session.version.MAIN_HW = info_p{i}(length('MAIN_HW')+2:end);
                                case 'SW'
                                    app.Data.session.version.SW = info_p{i}(length('SW')+2:end);
                                case 'VM1_FPGA'
                                    app.Data.session.version.VM1_FPGA = info_p{i}(length('VM1_FPGA')+2:end);
                                case 'VM2_FPGA'
                                    app.Data.session.version.VM2_FPGA = info_p{i}(length('VM2_FPGA')+2:end);
                            end
                        end
                    catch
                        app.Data.session.version.DSP_FW = -1;
                        app.Data.session.version.DSP_HW = -1;
                        app.Data.session.version.MAC = -1;
                        app.Data.session.version.MAIN_FPGA = -1;
                        app.Data.session.version.MAIN_HW = -1;
                        app.Data.session.version.SW = -1;
                        app.Data.session.version.VM1_FPGA = -1;
                        app.Data.session.version.VM2_FPGA = -1;
                    end
                    
                    % Duration
                    try
                        fid = fopen(strcat(app.Data.session.path_info,'\DurationTime.txt'));
                        info_p = textscan(fid,'%s');
                        info_p = info_p{1,1};
                        fclose(fid);
                        app.Data.session.duration = (str2double(info_p{2})-str2double(info_p{1}))/1000/60/60;
                    catch
                        app.Data.session.duration = 0;
                    end
                end
                
                %% Create result path
                if ~isfolder([app.Data.session.path_root '\result'])
                    mkdir([app.Data.session.path_root '\result']);
                end
                
                %% Data Import
                switch app.mode
                    case 'AT'
                        if app.Data.session.exist_b2b == true
                            [DataSet] = FxImport_EITb2b_app(app.Data.session.path_param);
                            [app.B2B,DataSet] = FxParam_ATb2b_app(DataSet);
                            app.Data.unit_TVeit = 'mL';
                            
                            app.Wave.t_hms = DataSet.Wave.TimeStamp;
                            app.Wave.RVS = DataSet.Wave.RVS;
                            app.Wave.Faw = DataSet.Wave.Faw;
                            app.Wave.Paw = DataSet.Wave.Paw;
                            app.Wave.Vaw = DataSet.Wave.Vaw;
                            app.Wave.SQI = DataSet.Wave.SQI;
                            app.ESUMenu.Enable = 'off';
                        elseif app.Data.session.exist_raw == true
                            [DataSet] = FxImport_EIT_app(app.Data.session.path_raw);
                            [app.B2B,DataSet] = FxParam_AT_v2_app(DataSet);
                            if isempty(app.B2B)
                                LoagingImage(app,'off');
                                msgbox('peak not found','','none');
                                error('peak not found') ;
                            end
                            app.Data.unit_TVeit = 'AU';
                            app.Wave.t_hms = DataSet.EIT.t_hms;
                            app.Wave.RVS = DataSet.EIT.RVS;
                            app.Wave.Faw = DataSet.Pressure.Faw;
                            app.Wave.Paw = DataSet.Pressure.Paw;
                            %                     app.Wave.Vaw = DataSet.Pressure.Vaw;
                            app.Wave.SQI = DataSet.EIT.SQI;
                            app.ESUMenu.Enable = 'on';
                        end
                        
                        %% Check flow sensor enable
                        if mean(abs(app.Wave.Paw))<0.01
                            app.Data.enPaw = false;
                        else
                            app.Data.enPaw = true;
                        end
                    case 'HV'
                        if app.Data.session.exist_b2b == true
                            [DataSet] = FxImport_EITb2b_app(app.Data.session.path_param);
                            [app.B2B,DataSet] = FxParam_HVb2b_app(DataSet);
                            app.Data.unit_TVeit = 'mL';
                            
                            app.Wave.t_hms = DataSet.Wave.TimeStamp;
                            app.Wave.CVS = DataSet.Wave.CVS;
                            app.Wave.ECG = DataSet.Wave.CVS;
%                             app.Wave.Faw = DataSet.Wave.Faw;
%                             app.Wave.Paw = DataSet.Wave.Paw;
%                             app.Wave.Vaw = DataSet.Wave.Vaw;
                            app.Wave.SQI = DataSet.Wave.SQI;
                        end
%                         elseif app.Data.session.exist_raw == true
%                             [DataSet] = FxImport_EIT_app(app.Data.session.path_raw);
%                             [app.B2B,DataSet] = FxParam_AT_v2_app(DataSet);
%                             if isempty(app.B2B)
%                                 LoagingImage(app,'off');
%                                 msgbox('peak not found','','none');
%                                 error('peak not found') ;
%                             end
%                             app.Data.unit_TVeit = 'AU';
%                             app.Wave.t_hms = DataSet.EIT.t_hms;
%                             app.Wave.RVS = DataSet.EIT.RVS;
%                             app.Wave.Faw = DataSet.Pressure.Faw;
%                             app.Wave.Paw = DataSet.Pressure.Paw;
%                             %                     app.Wave.Vaw = DataSet.Pressure.Vaw;
%                             app.Wave.SQI = DataSet.EIT.SQI;
%                         end
                        
                        %% Check flow sensor enable
                        if mean(app.Wave.Paw)<0.01
                            app.Data.enPaw = false;
                        else
                            app.Data.enPaw = true;
                        end
                end
                
                
                PatientInfoUpdate(app)
                UIGraphUpdate(app);
                UITableUpdate(app);
                
                AllButtonEnable(app,'on');
                LoagingImage(app,'off');
%             catch
%                 LoagingImage(app,'off');
%                 msgbox('data not found','','none');
%                 error('data not found') ;
%             end
        end

        % Close request function: AirTomDataViewer
        function AirTomDataViewerCloseRequest(app, event)
            try
%                 if isfield(app, 'Import_Option')
%                     delete(app.Import_Option);
%                 end
%                 if isfield(app, 'Import_Version')
%                     delete(app.Import_Version);
%                 end
                delete(app);
            catch
                delete(app);
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)

%                 if isfield(app, 'Import_Option')
%                     delete(app.Import_Option);
%                 end
%                 if isfield(app, 'Import_Version')
%                     delete(app.Import_Version);
%                 end
                delete(app);
        end

        % Button pushed function: GraphExportButton
        function GraphExportButtonPushed(app, event)
            % Graph 캡쳐
            [file,path] = uiputfile('*.png');
            if isequal(file,0) || isequal(path,0)
               disp('User clicked Cancel.')
            else
               exportgraphics(app.Graph_AT,[path file]);
               msgbox('Export Completed','','none');
            end
        end

        % Button pushed function: ZoomResetButton
        function ZoomResetButtonPushed(app, event)
            for cnt = 1:length(app.Graph)
                ylim(app.Graph(cnt),'auto');
            end
            xlim(app.Graph(1), [app.Wave.t_hms(1) app.Wave.t_hms(end)]);
        end

        % Menu selected function: BaselineMenu
        function BaselineMenuSelected(app, event)
            app.BaselineMenu.Checked = ~app.BaselineMenu.Checked;
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
                UITableUpdate(app);
            end
        end

        % Menu selected function: TVMenu
        function TVMenuSelected(app, event)
            app.TVMenu.Checked = ~app.TVMenu.Checked;
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
                UITableUpdate(app);
            end
        end

        % Menu selected function: RRMenu
        function RRMenuSelected(app, event)
            app.RRMenu.Checked = ~app.RRMenu.Checked;
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
                UITableUpdate(app);
            end
        end

        % Menu selected function: ESUMenu
        function ESUMenuSelected(app, event)
            app.ESUMenu.Checked = ~app.ESUMenu.Checked;
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
                UITableUpdate(app);
            end
        end

        % Menu selected function: ShowOutliersMenu
        function ShowOutliersMenuSelected(app, event)
            app.ShowOutliersMenu.Checked = ~app.ShowOutliersMenu.Checked;
            app.Opt.show_removed = app.ShowOutliersMenu.Checked;
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
%                 UITableUpdate(app);
            end
%             app.ESUMenu.Checked = ~app.ESUMenu.Checked;
%             if ~(isempty(app.B2B) && isempty(app.Wave))
%                 % draw graph, table
%                 UIGraphUpdate(app);
%                 UITableUpdate(app);
%             end
        end

        % Menu selected function: None
        function NoneMenuSelected(app, event)
            for cnt = 1:length(app.FilterMenu.Children)
                app.FilterMenu.Children(cnt).Checked = 0;
            end
            app.FilterMenu.Children(strcmp({app.FilterMenu.Children(:).Text},'None')).Checked = 1;
            app.Opt.Graph.avg_num = 0;

            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
                UITableUpdate(app);
            end
        end

        % Menu selected function: Median3
        function Median3MenuSelected(app, event)
            for cnt = 1:length(app.FilterMenu.Children)
                app.FilterMenu.Children(cnt).Checked = 0;
            end
            app.FilterMenu.Children(strcmp({app.FilterMenu.Children(:).Text},'Median (3 cycle)')).Checked = 1;
            app.Opt.Graph.avg_num = 1;

            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
                UITableUpdate(app);
            end
        end

        % Menu selected function: Median5
        function Median5MenuSelected(app, event)
            for cnt = 1:length(app.FilterMenu.Children)
                app.FilterMenu.Children(cnt).Checked = 0;
            end
            app.FilterMenu.Children(strcmp({app.FilterMenu.Children(:).Text},'Median (5 cycle)')).Checked = 1;
            app.Opt.Graph.avg_num = 2;

            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
                UITableUpdate(app);
            end
        end

        % Menu selected function: Median7
        function Median7MenuSelected(app, event)
            for cnt = 1:length(app.FilterMenu.Children)
                app.FilterMenu.Children(cnt).Checked = 0;
            end
            app.FilterMenu.Children(strcmp({app.FilterMenu.Children(:).Text},'Median (7 cycle)')).Checked = 1;
            app.Opt.Graph.avg_num = 3;

            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
                UITableUpdate(app);
            end
        end

        % Menu selected function: Size1
        function Size1Selected(app, event)
            for cnt = 1:length(app.MarkerSizeMenu.Children)
                app.MarkerSizeMenu.Children(cnt).Checked = 0;
            end
            app.MarkerSizeMenu.Children(strcmp({app.MarkerSizeMenu.Children(:).Text},'1')).Checked = 1;
            app.Opt.Graph.line_width = 1;
            app.Opt.Graph.marker_size = 4;
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
            end
        end

        % Menu selected function: Size2
        function Size2Selected(app, event)
            for cnt = 1:length(app.MarkerSizeMenu.Children)
                app.MarkerSizeMenu.Children(cnt).Checked = 0;
            end
            app.MarkerSizeMenu.Children(strcmp({app.MarkerSizeMenu.Children(:).Text},'2')).Checked = 1;
            app.Opt.Graph.line_width = 2;
            app.Opt.Graph.marker_size = 7;
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
            end
        end

        % Menu selected function: Size3
        function Size3Selected(app, event)
            for cnt = 1:length(app.MarkerSizeMenu.Children)
                app.MarkerSizeMenu.Children(cnt).Checked = 0;
            end
            app.MarkerSizeMenu.Children(strcmp({app.MarkerSizeMenu.Children(:).Text},'3')).Checked = 1;
            app.Opt.Graph.line_width = 3;
            app.Opt.Graph.marker_size = 10;
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
            end
        end

        % Menu selected function: dotMenu
        function dotMenuSelected(app, event)
            for cnt = 1:length(app.LineStyleMenu.Children)
                app.LineStyleMenu.Children(cnt).Checked = 0;
            end
            app.LineStyleMenu.Children(strcmp({app.LineStyleMenu.Children(:).Text},'dot (.)')).Checked = 1;
            app.Opt.Graph.marker_type = '.';
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
            end
        end

        % Menu selected function: lineMenu
        function lineMenuSelected(app, event)
            for cnt = 1:length(app.LineStyleMenu.Children)
                app.LineStyleMenu.Children(cnt).Checked = 0;
            end
            app.LineStyleMenu.Children(strcmp({app.LineStyleMenu.Children(:).Text},'line (-)')).Checked = 1;
            app.Opt.Graph.marker_type = '-';
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
            end
        end

        % Menu selected function: circleoMenu
        function circleoMenuSelected(app, event)
            for cnt = 1:length(app.LineStyleMenu.Children)
                app.LineStyleMenu.Children(cnt).Checked = 0;
            end
            app.LineStyleMenu.Children(strcmp({app.LineStyleMenu.Children(:).Text},'circle (o)')).Checked = 1;
            app.Opt.Graph.marker_type = 'o';
            if ~(isempty(app.B2B) && isempty(app.Wave))
                % draw graph, table
                UIGraphUpdate(app);
            end
        end

        % Value changed function: SampleRateDropDown
        function SampleRateDropDownValueChanged(app, event)
            UITableUpdate(app);
        end

        % Button pushed function: TableExportButton
        function TableExportButtonPushed(app, event)
            % Table csv로 출력부분
            [file,path] = uiputfile('*.xls');
            if isequal(file,0) || isequal(path,0)
               disp('User clicked Cancel.')
            else
               writetable(timetable2table(app.B2BTable),[path file]);
               msgbox('Export Completed','','none');
            end
        end

        % Image clicked function: Logo1
        function Logo1ImageClicked(app, event)
            web('https://bilabhealthcare.com/');
        end

        % Image clicked function: Logo2
        function Logo2ImageClicked(app, event)
            web('https://bilabhealthcare.com/');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AirTomDataViewer = uifigure('Visible', 'off');
            app.AirTomDataViewer.Name = 'AirTomDataViewer';
            app.AirTomDataViewer.CloseRequestFcn = createCallbackFcn(app, @AirTomDataViewerCloseRequest, true);
            app.FileMenu = uimenu(app.AirTomDataViewer);
            app.FileMenu.Text = 'File';
            app.OpenFolderMenu = uimenu(app.FileMenu);
            app.OpenFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFolderMenuSelected, true);
            app.OpenFolderMenu.Text = 'Open Folder';
            app.OpenFileMenu = uimenu(app.FileMenu);
            app.OpenFileMenu.Enable = 'off';
            app.OpenFileMenu.Text = 'Open File';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.OutlierMenu = uimenu(app.AirTomDataViewer);
            app.OutlierMenu.Text = 'Outlier';
            app.BaselineMenu = uimenu(app.OutlierMenu);
            app.BaselineMenu.MenuSelectedFcn = createCallbackFcn(app, @BaselineMenuSelected, true);
            app.BaselineMenu.Text = 'Baseline';
            app.TVMenu = uimenu(app.OutlierMenu);
            app.TVMenu.MenuSelectedFcn = createCallbackFcn(app, @TVMenuSelected, true);
            app.TVMenu.Text = 'TV';
            app.RRMenu = uimenu(app.OutlierMenu);
            app.RRMenu.MenuSelectedFcn = createCallbackFcn(app, @RRMenuSelected, true);
            app.RRMenu.Text = 'RR';
            app.ESUMenu = uimenu(app.OutlierMenu);
            app.ESUMenu.MenuSelectedFcn = createCallbackFcn(app, @ESUMenuSelected, true);
            app.ESUMenu.Enable = 'off';
            app.ESUMenu.Text = 'ESU';
            app.ShowOutliersMenu = uimenu(app.OutlierMenu);
            app.ShowOutliersMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowOutliersMenuSelected, true);
            app.ShowOutliersMenu.Text = 'Show Outliers';
            app.GraphMenu = uimenu(app.AirTomDataViewer);
            app.GraphMenu.Text = 'Graph';
            app.FilterMenu = uimenu(app.GraphMenu);
            app.FilterMenu.Checked = 'on';
            app.FilterMenu.Text = 'Filter';
            app.Median7 = uimenu(app.FilterMenu);
            app.Median7.MenuSelectedFcn = createCallbackFcn(app, @Median7MenuSelected, true);
            app.Median7.Checked = 'on';
            app.Median7.Text = 'Median (7 cycle)';
            app.Median5 = uimenu(app.FilterMenu);
            app.Median5.MenuSelectedFcn = createCallbackFcn(app, @Median5MenuSelected, true);
            app.Median5.Text = 'Median (5 cycle)';
            app.Median3 = uimenu(app.FilterMenu);
            app.Median3.MenuSelectedFcn = createCallbackFcn(app, @Median3MenuSelected, true);
            app.Median3.Text = 'Median (3 cycle)';
            app.None = uimenu(app.FilterMenu);
            app.None.MenuSelectedFcn = createCallbackFcn(app, @NoneMenuSelected, true);
            app.None.Text = 'None';
            app.LineStyleMenu = uimenu(app.GraphMenu);
            app.LineStyleMenu.Text = 'LineStyle';
            app.dotMenu = uimenu(app.LineStyleMenu);
            app.dotMenu.MenuSelectedFcn = createCallbackFcn(app, @dotMenuSelected, true);
            app.dotMenu.Checked = 'on';
            app.dotMenu.Text = 'dot (.)';
            app.lineMenu = uimenu(app.LineStyleMenu);
            app.lineMenu.MenuSelectedFcn = createCallbackFcn(app, @lineMenuSelected, true);
            app.lineMenu.Text = 'line (-)';
            app.circleoMenu = uimenu(app.LineStyleMenu);
            app.circleoMenu.MenuSelectedFcn = createCallbackFcn(app, @circleoMenuSelected, true);
            app.circleoMenu.Text = 'circle (o)';
            app.MarkerSizeMenu = uimenu(app.GraphMenu);
            app.MarkerSizeMenu.Text = 'MarkerSize';
            app.Size3 = uimenu(app.MarkerSizeMenu);
            app.Size3.MenuSelectedFcn = createCallbackFcn(app, @Size3Selected, true);
            app.Size3.Text = '3';
            app.Size2 = uimenu(app.MarkerSizeMenu);
            app.Size2.MenuSelectedFcn = createCallbackFcn(app, @Size2Selected, true);
            app.Size2.Checked = 'on';
            app.Size2.Text = '2';
            app.Size1 = uimenu(app.MarkerSizeMenu);
            app.Size1.MenuSelectedFcn = createCallbackFcn(app, @Size1Selected, true);
            app.Size1.Text = '1';
            app.DataselectionMenu = uimenu(app.GraphMenu);
            app.DataselectionMenu.Enable = 'off';
            app.DataselectionMenu.Text = 'Data selection';
            app.TabGroup = uitabgroup(app.AirTomDataViewer);
            app.GraphViewTab = uitab(app.TabGroup);
            app.GraphViewTab.Title = 'GraphView';
            app.GridLayout_Graph = uigridlayout(app.GraphViewTab);
            app.Logo1 = uiimage(app.GridLayout_Graph);
            app.Logo1.ImageClickedFcn = createCallbackFcn(app, @Logo1ImageClicked, true);
            app.Logo1.ImageSource = 'logo_png.png';
            app.Graph_AT = uipanel(app.GridLayout_Graph);
            app.Graph_AT.Visible = 'off';
            app.Panel_Info1 = uipanel(app.GridLayout_Graph);
            app.IDEditFieldLabel = uilabel(app.Panel_Info1);
            app.IDEditFieldLabel.Enable = 'off';
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField_1 = uieditfield(app.Panel_Info1, 'text');
            app.IDEditField_1.Editable = 'off';
            app.IDEditField_1.Enable = 'off';
            app.HeightLabel = uilabel(app.Panel_Info1);
            app.HeightLabel.Enable = 'off';
            app.HeightLabel.Text = 'Height';
            app.HeightStrField_1 = uieditfield(app.Panel_Info1, 'text');
            app.HeightStrField_1.Editable = 'off';
            app.HeightStrField_1.Enable = 'off';
            app.WeightEditField_3Label = uilabel(app.Panel_Info1);
            app.WeightEditField_3Label.Enable = 'off';
            app.WeightEditField_3Label.Text = 'Weight';
            app.WeightStrField_1 = uieditfield(app.Panel_Info1, 'text');
            app.WeightStrField_1.Editable = 'off';
            app.WeightStrField_1.Enable = 'off';
            app.AgeEditField_3Label = uilabel(app.Panel_Info1);
            app.AgeEditField_3Label.Enable = 'off';
            app.AgeEditField_3Label.Text = 'Age';
            app.AgeStrField_1 = uieditfield(app.Panel_Info1, 'text');
            app.AgeStrField_1.Editable = 'off';
            app.AgeStrField_1.Enable = 'off';
            app.GenderEditField_3Label = uilabel(app.Panel_Info1);
            app.GenderEditField_3Label.Enable = 'off';
            app.GenderEditField_3Label.Text = 'Gender';
            app.GenderStrField_1 = uieditfield(app.Panel_Info1, 'text');
            app.GenderStrField_1.Editable = 'off';
            app.GenderStrField_1.Enable = 'off';
            app.DateLabel = uilabel(app.Panel_Info1);
            app.DateLabel.Enable = 'off';
            app.DateLabel.Text = 'Date';
            app.DateStrField_1 = uieditfield(app.Panel_Info1, 'text');
            app.DateStrField_1.Editable = 'off';
            app.DateStrField_1.Enable = 'off';
            app.TimeLabel = uilabel(app.Panel_Info1);
            app.TimeLabel.Enable = 'off';
            app.TimeLabel.Text = 'Time';
            app.TimeStrField_1 = uieditfield(app.Panel_Info1, 'text');
            app.TimeStrField_1.Editable = 'off';
            app.TimeStrField_1.Enable = 'off';
            app.GraphExportButton = uibutton(app.GridLayout_Graph, 'push');
            app.GraphExportButton.ButtonPushedFcn = createCallbackFcn(app, @GraphExportButtonPushed, true);
            app.GraphExportButton.Enable = 'off';
            app.GraphExportButton.Text = 'Capture';
            app.ZoomResetButton = uibutton(app.GridLayout_Graph, 'push');
            app.ZoomResetButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomResetButtonPushed, true);
            app.ZoomResetButton.Enable = 'off';
            app.ZoomResetButton.Text = 'Zoom Reset';
            app.GraphHidePanel = uipanel(app.GridLayout_Graph);
            app.GraphHidePanel.Visible = 'off';
            app.LoadingImage_1 = uiimage(app.GridLayout_Graph);
            app.LoadingImage_1.Enable = 'off';
            app.LoadingImage_1.Visible = 'off';
            app.LoadingImage_1.ImageSource = 'Spin-1s-221px.gif';
            app.NumericViewTab = uitab(app.TabGroup);
            app.NumericViewTab.Title = 'NumericView';
            app.GridLayout_Table = uigridlayout(app.NumericViewTab);
            app.TableExportButton = uibutton(app.GridLayout_Table, 'push');
            app.TableExportButton.ButtonPushedFcn = createCallbackFcn(app, @TableExportButtonPushed, true);
            app.TableExportButton.Enable = 'off';
            app.TableExportButton.Text = 'Export';
            app.SampleRateDropDown = uidropdown(app.GridLayout_Table);
            app.SampleRateDropDown.Items = {'Breath by breath', '1 min (average)', '10 min (average)', '30 min (average)', '1 hour (average)', 'Selection'};
            app.SampleRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleRateDropDownValueChanged, true);
            app.SampleRateDropDown.Enable = 'off';
            app.SampleRateDropDown.Value = 'Breath by breath';
            app.Logo2 = uiimage(app.GridLayout_Table);
            app.Logo2.ImageClickedFcn = createCallbackFcn(app, @Logo2ImageClicked, true);
            app.Logo2.ImageSource = 'logo_png.png';
            app.UITable = uitable(app.GridLayout_Table);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.Panel_Info2 = uipanel(app.GridLayout_Table);
            app.IDEditField_2Label = uilabel(app.Panel_Info2);
            app.IDEditField_2Label.Enable = 'off';
            app.IDEditField_2Label.Text = 'ID';
            app.IDEditField_2 = uieditfield(app.Panel_Info2, 'text');
            app.IDEditField_2.Editable = 'off';
            app.IDEditField_2.Enable = 'off';
            app.HeightLabel_2 = uilabel(app.Panel_Info2);
            app.HeightLabel_2.Enable = 'off';
            app.HeightLabel_2.Text = 'Height';
            app.HeightStrField_2 = uieditfield(app.Panel_Info2, 'text');
            app.HeightStrField_2.Editable = 'off';
            app.HeightStrField_2.Enable = 'off';
            app.WeightEditField_3Label_2 = uilabel(app.Panel_Info2);
            app.WeightEditField_3Label_2.Enable = 'off';
            app.WeightEditField_3Label_2.Text = 'Weight';
            app.WeightStrField_2 = uieditfield(app.Panel_Info2, 'text');
            app.WeightStrField_2.Editable = 'off';
            app.WeightStrField_2.Enable = 'off';
            app.AgeEditField_3Label_2 = uilabel(app.Panel_Info2);
            app.AgeEditField_3Label_2.Enable = 'off';
            app.AgeEditField_3Label_2.Text = 'Age';
            app.AgeStrField_2 = uieditfield(app.Panel_Info2, 'text');
            app.AgeStrField_2.Editable = 'off';
            app.AgeStrField_2.Enable = 'off';
            app.GenderEditField_3Label_2 = uilabel(app.Panel_Info2);
            app.GenderEditField_3Label_2.Enable = 'off';
            app.GenderEditField_3Label_2.Text = 'Gender';
            app.GenderStrField_2 = uieditfield(app.Panel_Info2, 'text');
            app.GenderStrField_2.Editable = 'off';
            app.GenderStrField_2.Enable = 'off';
            app.DateLabel_2 = uilabel(app.Panel_Info2);
            app.DateLabel_2.Enable = 'off';
            app.DateLabel_2.Text = 'Date';
            app.DateStrField_2 = uieditfield(app.Panel_Info2, 'text');
            app.DateStrField_2.Editable = 'off';
            app.DateStrField_2.Enable = 'off';
            app.TimeLabel_2 = uilabel(app.Panel_Info2);
            app.TimeLabel_2.Enable = 'off';
            app.TimeLabel_2.Text = 'Time';
            app.TimeStrField_2 = uieditfield(app.Panel_Info2, 'text');
            app.TimeStrField_2.Editable = 'off';
            app.TimeStrField_2.Enable = 'off';
            app.LoadingImage_2 = uiimage(app.GridLayout_Table);
            app.LoadingImage_2.Enable = 'off';
            app.LoadingImage_2.Visible = 'off';
            app.LoadingImage_2.ImageSource = 'Spin-1s-221px.gif';
            % Show the figure after all components are created
            app.AirTomDataViewer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import_B2B_ver1_20221030

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AirTomDataViewer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AirTomDataViewer)
        end
    end
end