classdef Import_option < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportUIFigure          matlab.ui.Figure
        OptionPanel             matlab.ui.container.Panel
        MaskChDropDownLabel     matlab.ui.control.Label
        MaskChDropDown          matlab.ui.control.DropDown
        FormatDropDownLabel     matlab.ui.control.Label
        FormatDropDown          matlab.ui.control.DropDown
        EpadtypeDropDownLabel   matlab.ui.control.Label
        EpadtypeDropDown        matlab.ui.control.DropDown
        FlowDropDownLabel       matlab.ui.control.Label
        FlowDropDown            matlab.ui.control.DropDown
        ExportDropDownLabel     matlab.ui.control.Label
        ExportDropDown          matlab.ui.control.DropDown
        NsessionEditFieldLabel  matlab.ui.control.Label
        NsessionEditField       matlab.ui.control.NumericEditField
        IDEditFieldLabel        matlab.ui.control.Label
        IDEditField             matlab.ui.control.EditField
        DateEditFieldLabel      matlab.ui.control.Label
        DateEditField           matlab.ui.control.EditField
        DurationEditFieldLabel  matlab.ui.control.Label
        DurationEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Data)
            app.CallingApp = mainapp;
            app.IDEditField.Value = Data.session(Data.idx_Node(1)).ID;
            app.DateEditField.Value = Data.session(Data.idx_Node(1)).date;
%             app.DurationEditField.Value = [num2str(round(Data.duration_Node,2)) ' hour'];
            app.NsessionEditField.Value = length(Data.idx_Node);
%             app.CallingApp
        end

        % Close request function: ImportUIFigure
        function ImportUIFigureCloseRequest(app, event)
            app.CallingApp.OptionButton.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportUIFigure = uifigure('Visible', 'off');
            app.ImportUIFigure.Name = 'Import';
            app.ImportUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImportUIFigureCloseRequest, true);
            app.OptionPanel = uipanel(app.ImportUIFigure);
            app.OptionPanel.Title = 'Option';
            app.MaskChDropDownLabel = uilabel(app.OptionPanel);
            app.MaskChDropDownLabel.Text = 'Mask Ch';
            app.MaskChDropDown = uidropdown(app.OptionPanel);
            app.MaskChDropDown.Items = {'On', 'Off'};
            app.MaskChDropDown.Value = 'On';
            app.FormatDropDownLabel = uilabel(app.OptionPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.OptionPanel);
            app.FormatDropDown.Items = {'Magnitude', 'Phase', 'Complex'};
            app.FormatDropDown.Value = 'Magnitude';
            app.EpadtypeDropDownLabel = uilabel(app.OptionPanel);
            app.EpadtypeDropDownLabel.Text = 'E-pad type';
            app.EpadtypeDropDown = uidropdown(app.OptionPanel);
            app.EpadtypeDropDown.Items = {'Ventral-down', 'Dorsal-down', 'Ventral-up', 'Dorsal-up'};
            app.EpadtypeDropDown.Value = 'Ventral-down';
            app.FlowDropDownLabel = uilabel(app.OptionPanel);
            app.FlowDropDownLabel.Text = 'Flow';
            app.FlowDropDown = uidropdown(app.OptionPanel);
            app.FlowDropDown.Items = {'Adult', 'Neonate'};
            app.FlowDropDown.Value = 'Adult';
            app.ExportDropDownLabel = uilabel(app.ImportUIFigure);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.ImportUIFigure);
            app.ExportDropDown.Items = {'Process Only', 'Raw Data'};
            app.ExportDropDown.Value = 'Process Only';
            app.NsessionEditFieldLabel = uilabel(app.ImportUIFigure);
            app.NsessionEditFieldLabel.Text = 'N session';
            app.NsessionEditField = uieditfield(app.ImportUIFigure, 'numeric');
            app.NsessionEditField.Editable = 'off';
            app.IDEditFieldLabel = uilabel(app.ImportUIFigure);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.ImportUIFigure, 'text');
            app.IDEditField.Editable = 'off';
            app.DateEditFieldLabel = uilabel(app.ImportUIFigure);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.ImportUIFigure, 'text');
            app.DateEditField.Editable = 'off';
            app.DurationEditFieldLabel = uilabel(app.ImportUIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.ImportUIFigure, 'text');
            app.DurationEditField.Editable = 'off';
            % Show the figure after all components are created
            app.ImportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import_option(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportUIFigure)
        end
    end
end