classdef Import_version < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI_version                 matlab.ui.Figure
        SWEditFieldLabel           matlab.ui.control.Label
        SWEditField                matlab.ui.control.EditField
        DSP_HWEditField_3Label     matlab.ui.control.Label
        DSP_HWEditField            matlab.ui.control.EditField
        MAIN_HWEditField_3Label    matlab.ui.control.Label
        MAIN_HWEditField           matlab.ui.control.EditField
        VM1_FPGAEditFieldLabel     matlab.ui.control.Label
        VM1_FPGAEditField          matlab.ui.control.EditField
        MACEditFieldLabel          matlab.ui.control.Label
        MACEditField               matlab.ui.control.EditField
        DSP_FWEditField_2Label     matlab.ui.control.Label
        DSP_FWEditField            matlab.ui.control.EditField
        MAIN_FPGAEditField_2Label  matlab.ui.control.Label
        MAIN_FPGAEditField         matlab.ui.control.EditField
        VM2_FPGAEditFieldLabel     matlab.ui.control.Label
        VM2_FPGAEditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Data)
            app.CallingApp = mainapp;
            
            app.SWEditField.Value = Data.session(Data.idx_Node(1)).version.SW;
            app.DSP_HWEditField.Value = Data.session(Data.idx_Node(1)).version.DSP_HW;
            app.MAIN_HWEditField.Value = Data.session(Data.idx_Node(1)).version.MAIN_HW;
            app.VM1_FPGAEditField.Value = Data.session(Data.idx_Node(1)).version.VM1_FPGA;
            app.MACEditField.Value = Data.session(Data.idx_Node(1)).version.MAC;
            app.DSP_FWEditField.Value = Data.session(Data.idx_Node(1)).version.DSP_FW;
            app.MAIN_FPGAEditField.Value = Data.session(Data.idx_Node(1)).version.MAIN_FPGA;
            app.VM2_FPGAEditField.Value = Data.session(Data.idx_Node(1)).version.VM2_FPGA;
        
        end

        % Close request function: UI_version
        function UI_versionCloseRequest(app, event)
            app.CallingApp.VersionButton.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI_version = uifigure('Visible', 'off');
            app.UI_version.Name = 'MATLAB App';
            app.UI_version.CloseRequestFcn = createCallbackFcn(app, @UI_versionCloseRequest, true);
            app.SWEditFieldLabel = uilabel(app.UI_version);
            app.SWEditFieldLabel.Text = 'SW';
            app.SWEditField = uieditfield(app.UI_version, 'text');
            app.SWEditField.Editable = 'off';
            app.DSP_HWEditField_3Label = uilabel(app.UI_version);
            app.DSP_HWEditField_3Label.Text = 'DSP_HW';
            app.DSP_HWEditField = uieditfield(app.UI_version, 'text');
            app.DSP_HWEditField.Editable = 'off';
            app.MAIN_HWEditField_3Label = uilabel(app.UI_version);
            app.MAIN_HWEditField_3Label.Text = 'MAIN_HW';
            app.MAIN_HWEditField = uieditfield(app.UI_version, 'text');
            app.MAIN_HWEditField.Editable = 'off';
            app.VM1_FPGAEditFieldLabel = uilabel(app.UI_version);
            app.VM1_FPGAEditFieldLabel.Text = 'VM1_FPGA';
            app.VM1_FPGAEditField = uieditfield(app.UI_version, 'text');
            app.VM1_FPGAEditField.Editable = 'off';
            app.MACEditFieldLabel = uilabel(app.UI_version);
            app.MACEditFieldLabel.Text = 'MAC';
            app.MACEditField = uieditfield(app.UI_version, 'text');
            app.MACEditField.Editable = 'off';
            app.DSP_FWEditField_2Label = uilabel(app.UI_version);
            app.DSP_FWEditField_2Label.Text = 'DSP_FW';
            app.DSP_FWEditField = uieditfield(app.UI_version, 'text');
            app.DSP_FWEditField.Editable = 'off';
            app.MAIN_FPGAEditField_2Label = uilabel(app.UI_version);
            app.MAIN_FPGAEditField_2Label.Text = 'MAIN_FPGA';
            app.MAIN_FPGAEditField = uieditfield(app.UI_version, 'text');
            app.MAIN_FPGAEditField.Editable = 'off';
            app.VM2_FPGAEditFieldLabel = uilabel(app.UI_version);
            app.VM2_FPGAEditFieldLabel.Text = 'VM2_FPGA';
            app.VM2_FPGAEditField = uieditfield(app.UI_version, 'text');
            app.VM2_FPGAEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UI_version.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import_version(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI_version)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI_version)
        end
    end
end